classdef manualFrameRedo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ManualFrameRedoUIFigure    matlab.ui.Figure
        GridLayoutTabGroup         matlab.ui.container.GridLayout
        TabGroup                   matlab.ui.container.TabGroup
        RedoPupilandCRsTab         matlab.ui.container.Tab
        GridLayoutRows             matlab.ui.container.GridLayout
        GridLayoutInstructionsRow  matlab.ui.container.GridLayout
        Tab1InstructionsText       matlab.ui.control.Label
        Tab1InstructionsBorder     matlab.ui.container.Panel
        GridLayoutPlotRow          matlab.ui.container.GridLayout
        GridLayoutToolsColumn      matlab.ui.container.GridLayout
        TakenActionButtonGroup     matlab.ui.container.ButtonGroup
        UpdateFrameDataUButton     matlab.ui.control.RadioButton
        SetasNaNNButton            matlab.ui.control.RadioButton
        NoChangeButton             matlab.ui.control.RadioButton
        ManualFrameReDosPanel      matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        BadFramesListBox           matlab.ui.control.ListBox
        ToolSelectionButtonGroup   matlab.ui.container.ButtonGroup
        PupilButton                matlab.ui.control.ToggleButton
        ShowAllButton              matlab.ui.control.ToggleButton
        CR2Button                  matlab.ui.control.ToggleButton
        CR1Button                  matlab.ui.control.ToggleButton
        NoneButton                 matlab.ui.control.ToggleButton
        UIAxesPupilCRsNext         matlab.ui.control.UIAxes
        UIAxesPupilCRs             matlab.ui.control.UIAxes
        UIAxesPupilCRsPrev         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ParentApp; % Main app
    end
    
    properties (Access = public)
        vars;
        trackParams;
        frameData;
        data;
    end
    
    methods (Access = private)

        function ellipsePupilButton(app)
            app.Tab1InstructionsText.Text = sprintf("Hold left-click and draw ellipse around pupil boundary, then move, resize, and/or rotate the ellipse as needed.");
            setShapeVisibility(app, ["on","off","off"]);
            if ~app.data.ellipsePupilCompleted
                % User selects estimated pupil center using mouse
                app.data.ellipsePupilPlot = drawellipse(app.UIAxesPupilCRs, 'Color','r');
                app.CR1Button.Enable = "on";
                app.data.ellipsePupilCompleted = 1;
            end 
        end

        function circleCR1Button(app)
            app.Tab1InstructionsText.Text = sprintf("Hold left-click and draw circle around boundary of LEFT corneal reflection, then move and/or resize the circle as needed.");
            setShapeVisibility(app, ["off","on","off"]);
            if ~app.data.circleCR1Completed
                app.data.circleCR1Plot = drawcircle(app.UIAxesPupilCRs, 'Color','b');
                app.CR2Button.Enable = "on";
                app.data.circleCR1Completed = 1;
            end  
        end

        function circleCR2Button(app)
            app.Tab1InstructionsText.Text = sprintf("Hold left-click and draw circle around boundary of RIGHT corneal reflection, then move and/or resize the circle as needed.");
            setShapeVisibility(app, ["off","off","on"]);
            if ~app.data.circleCR2Completed
                app.data.circleCR2Plot = drawcircle(app.UIAxesPupilCRs, 'Color','c');
                app.ShowAllButton.Enable = "on";
                app.data.circleCR2Completed = 1;
            end  
        end

        function setShapeVisibility(app, status)
            try
                app.data.ellipsePupilPlot.Visible = status(1);
            catch
                app.data.ellipsePupilCompleted = 0;
            end

            try 
                app.data.circleCR1Plot.Visible = status(2);
            catch
                app.data.circleCR1Completed = 0;
            end
            
            try 
                app.data.circleCR2Plot.Visible = status(3);
            catch
                app.data.circleCR2Completed = 0;
            end
        end
        
        function [frameData, vars] = setTakenAction(app, frame, frameIdx, selection, frameData, vars, data)
            switch selection
                case "Update Frame Data (U)"
                    vars.framesListStatus(frameIdx) = 1;
                    app.data.ellipsePupilPlot.Visible = "on";
                    app.data.circleCR1Plot.Visible = "on";
                    app.data.circleCR2Plot.Visible = "on";
                    frameData(frame).cr1_x = data.circleCR1Plot.Center(1);
                    frameData(frame).cr1_y = data.circleCR1Plot.Center(2);
                    frameData(frame).cr1_r = data.circleCR1Plot.Radius;
            
                    frameData(frame).cr2_x = data.circleCR2Plot.Center(1);
                    frameData(frame).cr2_y = data.circleCR2Plot.Center(2);
                    frameData(frame).cr2_r = data.circleCR2Plot.Radius;
            
                    frameData(frame).pupil_x = data.ellipsePupilPlot.Center(1);
                    frameData(frame).pupil_y = data.ellipsePupilPlot.Center(2);
                    frameData(frame).pupil_r1 = data.ellipsePupilPlot.SemiAxes(2);
                    frameData(frame).pupil_r2 = data.ellipsePupilPlot.SemiAxes(1);
                    frameData(frame).pupil_angle = data.ellipsePupilPlot.RotationAngle;
                case "Set as NaN (N)"
                    vars.framesListStatus(frameIdx) = nan;
                    app.data.ellipsePupilPlot.Visible = "off";
                    app.data.circleCR1Plot.Visible = "off";
                    app.data.circleCR2Plot.Visible = "off";
                    frameData(frame).cr1_x = nan;
                    frameData(frame).cr1_y = nan;
                    frameData(frame).cr1_r = nan;
            
                    frameData(frame).cr2_x = nan;
                    frameData(frame).cr2_y = nan;
                    frameData(frame).cr2_r = nan;
            
                    frameData(frame).pupil_x = nan;
                    frameData(frame).pupil_y = nan;
                    frameData(frame).pupil_r1 = nan;
                    frameData(frame).pupil_r2 = nan;
                    frameData(frame).pupil_angle = nan;
                otherwise
                    vars.framesListStatus(frameIdx) = 0;
                    app.data.ellipsePupilPlot.Visible = "on";
                    app.data.circleCR1Plot.Visible = "on";
                    app.data.circleCR2Plot.Visible = "on";
            end
        end

        function getFrameData(app, frame, frameData)
            app.data.circleCR1Plot.Center(1) = frameData(frame).cr1_x;
            app.data.circleCR1Plot.Center(2) = frameData(frame).cr1_y;
            app.data.circleCR1Plot.Radius = frameData(frame).cr1_r;
    
            app.data.circleCR2Plot.Center(1) = frameData(frame).cr2_x;
            app.data.circleCR2Plot.Center(2) = frameData(frame).cr2_y;
            app.data.circleCR2Plot.Radius = frameData(frame).cr2_r;
    
            app.data.ellipsePupilPlot.Center(1) = frameData(frame).pupil_x;
            app.data.ellipsePupilPlot.Center(2) = frameData(frame).pupil_y;
            app.data.ellipsePupilPlot.SemiAxes(2) = frameData(frame).pupil_r1;
            app.data.ellipsePupilPlot.SemiAxes(1) = frameData(frame).pupil_r2;
            app.data.ellipsePupilPlot.RotationAngle = frameData(frame).pupil_angle;
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentapp, trackParams, frameData, frame, framesList)
            app.ParentApp = parentapp;
            app.trackParams = trackParams;
            app.frameData = frameData;
            app.vars.prevFrame = frame;
            if ~exist('framesList', 'var')
                framesList =  {int2str(frame)};
            end
            app.BadFramesListBox.Items = framesList;
            app.BadFramesListBox.Value = num2str(frame);
            app.vars.framesListStatus = zeros(1,length(framesList));
            % Initialize flag variables
            app.data.ellipsePupilCompleted = 0;
            app.data.circleCR1Completed = 0;
            app.data.circleCR2Completed = 0;
            % Plot figure
            img = readImg([0.05, 0.95], trackParams.cam, frame);
            cla(app.UIAxesPupilCRs);
            cla(app.UIAxesPupilCRsPrev);
            cla(app.UIAxesPupilCRsNext);
            app.data.imgCurr = imagesc(app.UIAxesPupilCRs, img);
            title(app.UIAxesPupilCRs, sprintf(['Frame ',num2str(frame)]), 'FontSize',18);
            colormap(app.UIAxesPupilCRs, 'gray');
            axis(app.UIAxesPupilCRs, 'image');
            set(app.UIAxesPupilCRs, 'ydir', 'reverse');
            set(app.UIAxesPupilCRs, 'Visible', 'off');
            set(get(app.UIAxesPupilCRs,'Title'), 'Visible','on');
            try
                imgPrev = readImg([0.05, 0.95], trackParams.cam, frame-1);
            catch
                imgPrev = zeros(size(img));
            end
            app.data.imgPrev = imagesc(app.UIAxesPupilCRsPrev, imgPrev);
            title(app.UIAxesPupilCRsPrev, sprintf(['Frame ',num2str(frame-1)]), 'FontSize',18);
            colormap(app.UIAxesPupilCRsPrev, 'gray');
            axis(app.UIAxesPupilCRsPrev, 'image');
            set(app.UIAxesPupilCRsPrev, 'ydir', 'reverse');
            set(app.UIAxesPupilCRsPrev, 'Visible', 'off');
            set(get(app.UIAxesPupilCRsPrev,'Title'), 'Visible','on');
            try
                imgNext = readImg([0.05, 0.95], trackParams.cam, frame+1);
            catch
                imgNext = zeros(size(img));
            end
            app.data.imgNext = imagesc(app.UIAxesPupilCRsNext, imgNext);
            title(app.UIAxesPupilCRsNext, sprintf(['Frame ',num2str(frame+1)]), 'FontSize',18);
            colormap(app.UIAxesPupilCRsNext, 'gray');
            axis(app.UIAxesPupilCRsNext, 'image');
            set(app.UIAxesPupilCRsNext, 'ydir', 'reverse');
            set(app.UIAxesPupilCRsNext, 'Visible', 'off');
            set(get(app.UIAxesPupilCRsNext,'Title'), 'Visible','on');
        end

        % Close request function: ManualFrameRedoUIFigure
        function ManualFrameRedoUIFigureCloseRequest(app, event)
            try
                framesListStatus = app.vars.framesListStatus;
                framesList = app.BadFramesListBox.Items;
                unassignedFrameIds = find(framesListStatus == 0);
                if ~isempty(unassignedFrameIds)
                    unassignedFrames = framesList(unassignedFrameIds');
                    unassignedFrameNumbers = cellfun(@(x) str2double(x), unassignedFrames);
                    % unassignedStrings = cellfun(@(c) c{1}, unassignedFrames, 'UniformOutput', false);
                    unassignedText = strjoin(unassignedFrames, ', ');
                    confirmtxt = sprintf(['The following frames were not assigned an action:\n\n', ...
                                          unassignedText, ...
                                          '\n\nWhat action would you like performed on these frames?']);
                    selection = uiconfirm(app.ManualFrameRedoUIFigure, ...
                                          confirmtxt, ...
                                          'Close Confirmation', ...
                                          'Options',["Proceed with No Changes","Set Frames as NaN","Cancel"], ...
                                          'DefaultOption',1, ...
                                          'CancelOption',3);
                    if selection == "Cancel"
                        return
                    elseif selection == "Set Frames as NaN"
                        for i = 1:length(unassignedFrameNumbers)
                            frame = unassignedFrameNumbers(i);
                            app.frameData(frame).cr1_x = nan;
                            app.frameData(frame).cr1_y = nan;
                            app.frameData(frame).cr1_r = nan;
                    
                            app.frameData(frame).cr2_x = nan;
                            app.frameData(frame).cr2_y = nan;
                            app.frameData(frame).cr2_r = nan;
                    
                            app.frameData(frame).pupil_x = nan;
                            app.frameData(frame).pupil_y = nan;
                            app.frameData(frame).pupil_r1 = nan;
                            app.frameData(frame).pupil_r2 = nan;
                            app.frameData(frame).pupil_angle = nan;
                        end
                    end
                end
                closeManualFrameRedoApp(app.ParentApp, app.frameData);
            catch msgid
                fprintf('Error upon attempting to close Manual Frame Redo App:\n');
                disp(msgid.message);
                delete(app)
            end
        end

        % Selection changed function: ToolSelectionButtonGroup
        function ToolSelectionButtonGroupSelectionChanged(app, event)
            selectedButton = app.ToolSelectionButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Pupil'
                    ellipsePupilButton(app);
                case 'CR 1'
                    circleCR1Button(app);
                case 'CR 2'
                    circleCR2Button(app);
                case 'Show All'
                    app.Tab1InstructionsText.Text = sprintf("Move, resize, or rotate any of the drawn shapes as needed. You may close this window.");
                    setShapeVisibility(app, ["on","on","on"]);
            end
        end

        % Value changed function: BadFramesListBox
        function BadFramesListBoxValueChanged(app, event)
            frame = str2double(app.BadFramesListBox.Value);
            frameIdx = app.BadFramesListBox.ValueIndex;
            app.data.imgCurr.CData = readImg([0.05, 0.95], app.trackParams.cam, frame);
            app.data.imgPrev.CData = readImg([0.05, 0.95], app.trackParams.cam, frame-1);
            app.data.imgNext.CData = readImg([0.05, 0.95], app.trackParams.cam, frame+1);
            if isnan(app.vars.framesListStatus(frameIdx))
                app.SetasNaNNButton.Value = 1;
                app.data.ellipsePupilPlot.Visible = "off";
                app.data.circleCR1Plot.Visible = "off";
                app.data.circleCR2Plot.Visible = "off";
            elseif app.vars.framesListStatus(frameIdx)
                app.UpdateFrameDataUButton.Value = 1;
                getFrameData(app, frame, app.frameData);
                app.data.ellipsePupilPlot.Visible = "on";
                app.data.circleCR1Plot.Visible = "on";
                app.data.circleCR2Plot.Visible = "on";
            else
                app.NoChangeButton.Value = 1;
                app.data.ellipsePupilPlot.Visible = "on";
                app.data.circleCR1Plot.Visible = "on";
                app.data.circleCR2Plot.Visible = "on";
            end
            app.vars.prevFrame = frame;
        end

        % Selection changed function: TakenActionButtonGroup
        function TakenActionButtonGroupSelectionChanged(app, event)
            selection = app.TakenActionButtonGroup.SelectedObject.Text;
            frame = str2double(app.BadFramesListBox.Value);
            frameIdx = app.BadFramesListBox.ValueIndex;
            [app.frameData, app.vars] = setTakenAction(app, frame, frameIdx, selection, app.frameData, app.vars, app.data);
        end

        % Key press function: ManualFrameRedoUIFigure
        function ManualFrameRedoUIFigureKeyPress(app, event)
            key = event.Key;
            if key == "n"
                app.SetasNaNNButton.Value = 1;
            elseif key == "u"
                app.UpdateFrameDataUButton.Value = 1;
            end
            selection = app.TakenActionButtonGroup.SelectedObject.Text;
            frame = str2double(app.BadFramesListBox.Value);
            frameIdx = app.BadFramesListBox.ValueIndex;
            [app.frameData, app.vars] = setTakenAction(app, frame, frameIdx, selection, app.frameData, app.vars, app.data);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ManualFrameRedoUIFigure = uifigure('Visible', 'off');
            app.ManualFrameRedoUIFigure.Name = 'Manual Frame Redo';
            app.ManualFrameRedoUIFigure.CloseRequestFcn = createCallbackFcn(app, @ManualFrameRedoUIFigureCloseRequest, true);
            app.ManualFrameRedoUIFigure.KeyPressFcn = createCallbackFcn(app, @ManualFrameRedoUIFigureKeyPress, true);
            app.GridLayoutTabGroup = uigridlayout(app.ManualFrameRedoUIFigure);
            app.TabGroup = uitabgroup(app.GridLayoutTabGroup);
            app.RedoPupilandCRsTab = uitab(app.TabGroup);
            app.RedoPupilandCRsTab.Title = 'Redo Pupil and CRs';
            app.GridLayoutRows = uigridlayout(app.RedoPupilandCRsTab);
            app.GridLayoutPlotRow = uigridlayout(app.GridLayoutRows);
            app.UIAxesPupilCRsPrev = uiaxes(app.GridLayoutPlotRow);
            app.UIAxesPupilCRsPrev.XTick = [];
            app.UIAxesPupilCRsPrev.XTickLabel = '';
            app.UIAxesPupilCRsPrev.YTick = [];
            app.UIAxesPupilCRsPrev.YTickLabel = '';
            app.UIAxesPupilCRsPrev.ZTick = [];
            app.UIAxesPupilCRsPrev.ZTickLabel = '';
            app.UIAxesPupilCRs = uiaxes(app.GridLayoutPlotRow);
            app.UIAxesPupilCRs.XTick = [];
            app.UIAxesPupilCRs.XTickLabel = '';
            app.UIAxesPupilCRs.YTick = [];
            app.UIAxesPupilCRs.YTickLabel = '';
            app.UIAxesPupilCRs.ZTick = [];
            app.UIAxesPupilCRs.ZTickLabel = '';
            app.UIAxesPupilCRsNext = uiaxes(app.GridLayoutPlotRow);
            app.UIAxesPupilCRsNext.XTick = [];
            app.UIAxesPupilCRsNext.XTickLabel = '';
            app.UIAxesPupilCRsNext.YTick = [];
            app.UIAxesPupilCRsNext.YTickLabel = '';
            app.UIAxesPupilCRsNext.ZTick = [];
            app.UIAxesPupilCRsNext.ZTickLabel = '';
            app.GridLayoutToolsColumn = uigridlayout(app.GridLayoutPlotRow);
            app.ToolSelectionButtonGroup = uibuttongroup(app.GridLayoutToolsColumn);
            app.ToolSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ToolSelectionButtonGroupSelectionChanged, true);
            app.ToolSelectionButtonGroup.Title = 'Tool Selection';
            app.NoneButton = uitogglebutton(app.ToolSelectionButtonGroup);
            app.NoneButton.Enable = 'off';
            app.NoneButton.Visible = 'off';
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.CR1Button = uitogglebutton(app.ToolSelectionButtonGroup);
            app.CR1Button.Enable = 'off';
            app.CR1Button.Text = 'CR 1';
            app.CR2Button = uitogglebutton(app.ToolSelectionButtonGroup);
            app.CR2Button.Enable = 'off';
            app.CR2Button.Text = 'CR 2';
            app.ShowAllButton = uitogglebutton(app.ToolSelectionButtonGroup);
            app.ShowAllButton.Enable = 'off';
            app.ShowAllButton.Text = 'Show All';
            app.PupilButton = uitogglebutton(app.ToolSelectionButtonGroup);
            app.PupilButton.Text = 'Pupil';
            app.ManualFrameReDosPanel = uipanel(app.GridLayoutToolsColumn);
            app.ManualFrameReDosPanel.Title = 'Manual Frame Re-Dos';
            app.GridLayout3 = uigridlayout(app.ManualFrameReDosPanel);
            app.BadFramesListBox = uilistbox(app.GridLayout3);
            app.BadFramesListBox.Items = {'None'};
            app.BadFramesListBox.ValueChangedFcn = createCallbackFcn(app, @BadFramesListBoxValueChanged, true);
            app.BadFramesListBox.Value = 'None';
            app.TakenActionButtonGroup = uibuttongroup(app.GridLayoutToolsColumn);
            app.TakenActionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TakenActionButtonGroupSelectionChanged, true);
            app.TakenActionButtonGroup.Title = 'Taken Action';
            app.NoChangeButton = uiradiobutton(app.TakenActionButtonGroup);
            app.NoChangeButton.Text = 'No Change';
            app.NoChangeButton.Value = true;
            app.SetasNaNNButton = uiradiobutton(app.TakenActionButtonGroup);
            app.SetasNaNNButton.Text = 'Set as NaN (N)';
            app.UpdateFrameDataUButton = uiradiobutton(app.TakenActionButtonGroup);
            app.UpdateFrameDataUButton.Text = 'Update Frame Data (U)';
            app.GridLayoutInstructionsRow = uigridlayout(app.GridLayoutRows);
            app.Tab1InstructionsBorder = uipanel(app.GridLayoutInstructionsRow);
            app.Tab1InstructionsText = uilabel(app.GridLayoutInstructionsRow);
            app.Tab1InstructionsText.Text = 'Select "Pupil" tool on the left-hand side to begin.';
            % Show the figure after all components are created
            app.ManualFrameRedoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = manualFrameRedo(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ManualFrameRedoUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ManualFrameRedoUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ManualFrameRedoUIFigure)
        end
    end
end