classdef eyeCalAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        eyeCalibrationUIFigure          matlab.ui.Figure
        GridLayoutTabGroup              matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        EyeTrackingTab1                 matlab.ui.container.Tab
        GridLayoutTab1Rows              matlab.ui.container.GridLayout
        GridLayoutTab1MainRow           matlab.ui.container.GridLayout
        ManualFrameReDosPanel           matlab.ui.container.Panel
        GridLayoutTab1RedosPanel        matlab.ui.container.GridLayout
        GridLayoutCustomFrame           matlab.ui.container.GridLayout
        DebugButton                     matlab.ui.control.Button
        CustomFrameLabel                matlab.ui.control.Label
        CustomFrameInteger              matlab.ui.control.NumericEditField
        BadFramesListBox                matlab.ui.control.ListBox
        RedoSelectedFrameButton         matlab.ui.control.Button
        RedoCustomFrameButton           matlab.ui.control.Button
        GridLayoutTab1ParametersPanel   matlab.ui.container.GridLayout
        SetupEyeTrackingButton          matlab.ui.control.Button
        RunEyeTrackingButton            matlab.ui.control.Button
        CameraButtonGroup               matlab.ui.container.ButtonGroup
        Camera2Button                   matlab.ui.control.RadioButton
        Camera1Button                   matlab.ui.control.RadioButton
        UIAxesTab1EyeTracking           matlab.ui.control.UIAxes
        GridLayoutTab1FolderRow         matlab.ui.container.GridLayout
        FolderTextTab1                  matlab.ui.control.EditField
        FolderButtonTab1                matlab.ui.control.Button
        UIAxesTab1Radii                 matlab.ui.control.UIAxes
        UIAxesTab1CRPos                 matlab.ui.control.UIAxes
        UIAxesTab1PupilPos              matlab.ui.control.UIAxes
        EyeAnalysisTab2                 matlab.ui.container.Tab
        GridLayoutTab2Rows              matlab.ui.container.GridLayout
        FolderTextTab2                  matlab.ui.control.EditField
        GridLayoutTab2MainRow           matlab.ui.container.GridLayout
        GridLayout                      matlab.ui.container.GridLayout
        OverwriteBestChannelButton      matlab.ui.control.Button
        BestChannelButtonGroup          matlab.ui.container.ButtonGroup
        Magnet2Button                   matlab.ui.control.RadioButton
        Magnet1Button                   matlab.ui.control.RadioButton
        GridLayoutTab2ParametersPanel   matlab.ui.container.GridLayout
        RigNumberDropDown               matlab.ui.control.DropDown
        RigNumberLabel                  matlab.ui.control.Label
        GridLayoutStimulusFrequencyEditField  matlab.ui.container.GridLayout
        StimulusFrequencyEditField      matlab.ui.control.NumericEditField
        EyeMagnetLocationLabel          matlab.ui.control.Label
        CameraTimeStampsLabel           matlab.ui.control.Label
        StimulusFrequencyLabel          matlab.ui.control.Label
        EyeMagnetLocationDropDown       matlab.ui.control.DropDown
        CameraTimeStampsDropDown        matlab.ui.control.DropDown
        CalibrationQualityAnalysisPanel  matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        CalibrationAnalysisPlotsButton  matlab.ui.control.Button
        DesaccadeforScalingFactorsPanel  matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        DesaccadeDataButton             matlab.ui.control.Button
        AlignMagnetandVideoDataPanel    matlab.ui.container.Panel
        Magnet2CheckBox                 matlab.ui.control.CheckBox
        Magnet1CheckBox                 matlab.ui.control.CheckBox
        AlignDataButton                 matlab.ui.control.Button
        UIAxesAlignedVelocities         matlab.ui.control.UIAxes
        UIAxesAlignedPositions          matlab.ui.control.UIAxes
    end

    %% Private app properties
    properties (Access = private)
        % Sub-app objects
        SetupEyeTrackingApp;
        ManualFrameRedoApp;
        AlignMagnetVideoApp;
        DesaccadeScalingFactorsApp;
        pdlg;            % Dialog to disable/enable main figure
        trackParams;     % Eye tracking-related parameters
        frameData = [];  % Eye tracking-related data
        vars;            % Eye analysis-related parameters/data
    end

    %% Private app functions
    methods (Access = private)
        
        function resetAppUI(app)
            % Clear all figures
            cla(app.UIAxesTab1EyeTracking);
            cla(app.UIAxesTab1PupilPos);
            cla(app.UIAxesTab1CRPos);
            cla(app.UIAxesTab1Radii);
            cla(app.UIAxesAlignedPositions);
            cla(app.UIAxesAlignedVelocities);
            % Enable/disable controls to default state
            app.RunEyeTrackingButton.Enable = "off";
            app.RedoSelectedFrameButton.Enable = "off";
            app.RedoCustomFrameButton.Enable = "off";
            app.AlignDataButton.Enable = "off";
            app.Magnet1CheckBox.Enable = "off";
            app.Magnet2CheckBox.Enable = "off";
            app.DesaccadeDataButton.Enable = "off";
            app.CalibrationAnalysisPlotsButton.Enable = "off";
            app.BestChannelButtonGroup.Enable = "off";
            app.Magnet1Button.Enable = "off";
            app.Magnet2Button.Enable = "off";
            app.OverwriteBestChannelButton.Enable = "off";
        end
        
        function [trackParams] = setTrackParamsCam(app, vars, trackParams)
            status = [" (incomplete)", " (COMPLETED)"];
            app.Camera1Button.Text = "1" + status(vars.cameraHasTrackingData(1)+1);
            app.Camera2Button.Text = "2" + status(vars.cameraHasTrackingData(2)+1);
            if app.Camera1Button.Value
                trackParams.cam = 1;
            else
                trackParams.cam = 2;
            end
            if vars.cameraHasTrackingData(1) && vars.cameraHasTrackingData(2)
                app.AlignDataButton.Enable = "on";
            end
        end

        function setAlignPlotsVisibility(app)
            % Ensure something is already plotted
            if isempty(app.UIAxesAlignedPositions.Children)
                return
            end
            % Change visibility based on checkbox states
            if app.Magnet1CheckBox.Value
                app.UIAxesAlignedPositions.Children(2).Visible = "on";
                app.UIAxesAlignedVelocities.Children(2).Visible = "on";
            else
                app.UIAxesAlignedPositions.Children(2).Visible = "off";
                app.UIAxesAlignedVelocities.Children(2).Visible = "off";
            end
            if app.Magnet2CheckBox.Value
                app.UIAxesAlignedPositions.Children(1).Visible = "on";
                app.UIAxesAlignedVelocities.Children(1).Visible = "on";
            else
                app.UIAxesAlignedPositions.Children(1).Visible = "off";
                app.UIAxesAlignedVelocities.Children(1).Visible = "off";
            end
        end

    end

    %% Public app functions
    methods (Access = public)
        
        function closeSetupEyeTrackingApp(app, trackParams, allDone)
            delete(app.SetupEyeTrackingApp);
            if allDone
                app.trackParams = trackParams;
                app.BadFramesListBox.Items = {'None'};
                app.RedoSelectedFrameButton.Enable = "off";
                app.RedoCustomFrameButton.Enable = "off";
                app.RunEyeTrackingButton.Enable = "on";
                if app.trackParams.cam == 1
                    app.vars.cameraHasTrackingData(1) = 0;
                    app.Camera1Button.Text = '1 (Incomplete)';
                else
                    app.vars.cameraHasTrackingData(2) = 0;
                    app.Camera2Button.Text = '2 (Incomplete)';
                end
                try
                    [app.trackParams] = setupEyeTracking_APP(app, app.trackParams);
                    uialert(app.eyeCalibrationUIFigure, ...
                            'Eye Tracking was successfully configured!', ...
                            'Setup Successful', ...
                            'Icon','success');
                catch
                    uialert(app.eyeCalibrationUIFigure, ...
                            'Unable to detect pupil and CRs. Please run Setup Eye Tracking again.', ...
                            'Setup Unsuccessful');
                end
            end
            close(app.pdlg);
        end

        function closeManualFrameRedoApp(app, frameData)
            delete(app.ManualFrameRedoApp);
            app.frameData = frameData;
            frameData = app.frameData;
            save(['videoresults_cam', num2str(app.trackParams.cam), '.mat'], 'frameData')
            fprintf('\nManual frame redo results saved!')
            [app.vars, app.trackParams, app.frameData] = loadExistingTrackingData_APP(app, app.vars, app.trackParams, app.frameData);
            close(app.pdlg);
        end

        function closeAlignMagnetVideoApp(app, vars, selection)
            delete(app.AlignMagnetVideoApp);
            if selection
                app.vars = vars;
                app.vars = plotAlignedTraces_APP(app, app.vars);
                app.Magnet1CheckBox.Enable = "on";
                app.Magnet2CheckBox.Enable = "on";
                app.DesaccadeDataButton.Enable = "on";
            end
            close(app.pdlg);
        end

        function closeDesaccadeScalingFactorsApp(app, vars, selection)
            delete(app.DesaccadeScalingFactorsApp);
            if selection
                app.vars = vars;
                switch vars.vid.bestMagChannel
                    case 1
                        app.Magnet1Button.Value = 1;
                    case 2
                        app.Magnet2Button.Value = 1;
                end
                app.vars = plotAlignedTraces_APP(app, app.vars);
                app.CalibrationAnalysisPlotsButton.Enable = "on";
                app.BestChannelButtonGroup.Enable = "on";
                app.Magnet1Button.Enable = "on";
                app.Magnet2Button.Enable = "on";
                app.OverwriteBestChannelButton.Enable = "on";
            end
            close(app.pdlg);
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, currentFolder)
            % Get path to the folder where app is located
            [appFolderPath, ~, ~] = fileparts(mfilename('fullpath'));
            app.vars.appFolderPath = genpath(appFolderPath);
            % Temporarily add folder/subfolders as a search path for MATLAB
            addpath(app.vars.appFolderPath); 
            % Update "Current Folder", as seen by app, with "currentFolder"
            % Without this, app will error if run as a favorite command
            if ~exist("currentFolder","var"), currentFolder=pwd; end
            cd(currentFolder);
            % Set folder text on UI to display the current folder
            app.FolderTextTab1.Value = currentFolder;
            app.FolderTextTab2.Value = currentFolder;
            app.vars.currentFolderPath = app.FolderTextTab1.Value;
            % Check if the current folder is valid and/or has existing data
            app.vars = checkCurrentFolder_APP(app, app.vars);
            app.trackParams = setTrackParamsCam(app, app.vars, app.trackParams);
            % Define the minimal contrast settings for displayed images
            % Note: This setting is only for visualization of camera images
            app.trackParams.defaultImgAdj = [0.05, 0.95];
            % Get and/or set Rig number in dropdown on second tab
            app.RigNumberDropDown.Value = readwriteRigNumber(app.RigNumberDropDown.Value, 1);
            % Load any existing eye tracking data and plot those results
            [app.vars, app.trackParams, app.frameData] = loadExistingTrackingData_APP(app, app.vars, app.trackParams);
        end

        % Button pushed function: FolderButtonTab1
        function FolderButtonTab1Pushed(app, event)
            newFolder = uigetdir(app.vars.currentFolderPath);
            cd(newFolder);
            app.FolderTextTab1.Value = newFolder;
            app.FolderTextTab2.Value = newFolder;
            app.vars.currentFolderPath = newFolder;
            resetAppUI(app);
            app.vars = checkCurrentFolder_APP(app, app.vars);
            app.trackParams = setTrackParamsCam(app, app.vars, app.trackParams);
            [app.vars, app.trackParams, app.frameData] = loadExistingTrackingData_APP(app, app.vars, app.trackParams);
        end

        % Value changed function: FolderTextTab1
        function FolderTextTab1ValueChanged(app, event)
            newFolder = app.FolderTextTab1.Value;
            try
                cd(newFolder)
                app.FolderTextTab1.Value = newFolder;
                app.FolderTextTab2.Value = newFolder;
                app.vars.currentFolderPath = newFolder;
                resetAppUI(app);
                app.vars = checkCurrentFolder_APP(app, app.vars);
                app.trackParams = setTrackParamsCam(app, app.vars, app.trackParams);
                [app.vars, app.trackParams, app.frameData] = loadExistingTrackingData_APP(app, app.vars, app.trackParams);
            catch 
                uialert(app.eyeCalibrationUIFigure, ...
                    'Folder not found! Please try again.', ...
                    'Invalid Folder');
            end
        end

        % Close request function: eyeCalibrationUIFigure
        function eyeCalibrationUIFigureCloseRequest(app, event)
            % Delete all other app data before closing
            delete(app.SetupEyeTrackingApp);
            delete(app.ManualFrameRedoApp);
            delete(app.AlignMagnetVideoApp);
            delete(app.DesaccadeScalingFactorsApp);
            %rmpath(app.vars.appFolderPath);  % Remove app folder from MATLAB search path
            close(app.pdlg);
            delete(app)
        end

        % Selection changed function: CameraButtonGroup
        function CameraButtonGroupSelectionChanged(app, event)
            pbar = uiprogressdlg(app.eyeCalibrationUIFigure, ...
                'Title','Please Wait', ...
                'Message','Checking camera for existing data.');
            pbar.Value = .2; 
            app.trackParams = setTrackParamsCam(app, app.vars, app.trackParams);
            pbar.Value = .6; 
            [app.vars, app.trackParams, app.frameData] = loadExistingTrackingData_APP(app, app.vars, app.trackParams);
            pbar.Value = .9; 
            pause(0.01);
            close(pbar);
        end

        % Button pushed function: SetupEyeTrackingButton
        function SetupEyeTrackingButtonPushed(app, event)
            % Alert user of potentially overwriting existing data
            if app.vars.cameraHasTrackingData(app.trackParams.cam)
                confirm = ['Eye tracking has already been completed for Camera ', ...
                           num2str(app.trackParams.cam), ...
                           '! Would you like to overwrite the existing eye tracking configuration?'];
                switch uiconfirm(app.eyeCalibrationUIFigure, confirm, 'Overwrite Existing Configuration')
                    case 'OK'
                        % Do nothing
                    case 'Cancel'
                        return
                end
            end
            % Open setupEyeTracking.mlapp app
            app.pdlg = uiprogressdlg(app.eyeCalibrationUIFigure, ...
                'Title','Setting Up Eye Tracking...');
            app.SetupEyeTrackingApp = setupEyeTracking(app, app.trackParams);
        end

        % Button pushed function: RunEyeTrackingButton
        function RunEyeTrackingButtonPushed(app, event)
            % Alert user of potentially overwriting existing data
            if app.vars.cameraHasTrackingData(app.trackParams.cam)
                confirm = ['Eye tracking has already been completed for Camera ', ...
                           num2str(app.trackParams.cam), ...
                           '! Would you like to overwrite the existing eye tracking configuration?'];
                switch uiconfirm(app.eyeCalibrationUIFigure, confirm, 'Overwrite Existing Configuration')
                    case 'OK'
                        % Do nothing
                    case 'Cancel'
                        return
                end
            end
            % Run automatic detection of pupil and corneal reflections
            % Saves to "videoresults_cam" file
            [app.trackParams, app.frameData] = eyeAnalysis_APP(app, app.trackParams);
            % Identify poorly identified frames and update Re-do list
            [app.vars, app.frameData] = updateBadFramesListBox_APP(app, app.vars, app.trackParams, app.frameData, 1);
            app.trackParams = setTrackParamsCam(app, app.vars, app.trackParams);
        end

        % Button pushed function: RedoSelectedFrameButton
        function RedoSelectedFrameButtonPushed(app, event)
            frame = str2double(app.BadFramesListBox.Value);
            framesList = app.BadFramesListBox.Items;
            % Open manualFrameRedo.mlapp
            app.pdlg = uiprogressdlg(app.eyeCalibrationUIFigure, ...
                'Title','Re-doing Selected Frame...');
            app.ManualFrameRedoApp = manualFrameRedo(app, app.trackParams, app.frameData, frame, framesList);
        end

        % Button pushed function: RedoCustomFrameButton
        function RedoCustomFrameButtonPushed(app, event)
            frame = app.CustomFrameInteger.Value;
            if isempty(frame)
                uialert(app.eyeCalibrationUIFigure, ...
                    'Custom Frame value must be a positive integer.', ...
                    'Invalid Custom Frame Value');
            end
            if frame < app.trackParams.nImages
                % Open manualFrameRedo.mlapp
                app.pdlg = uiprogressdlg(app.eyeCalibrationUIFigure, ...
                    'Title','Re-doing Custom Frame...');
                app.ManualFrameRedoApp = manualFrameRedo(app, app.trackParams, app.frameData, frame);
            else
                uialert(app.eyeCalibrationUIFigure, ...
                    'The frame number provided was invalid. Please try again.', ...
                    'Invalid Frame');
            end
        end

        % Button pushed function: AlignDataButton
        function AlignDataButtonPushed(app, event)
            app.CalibrationAnalysisPlotsButton.Enable = "off";
            app.BestChannelButtonGroup.Enable = "off";
            app.Magnet1Button.Enable = "off";
            app.Magnet2Button.Enable = "off";
            app.OverwriteBestChannelButton.Enable = "off";
            app.vars.stimFreq = app.StimulusFrequencyEditField.Value;
            app.vars = createAnalysisInfoData_APP(app, app.vars);
            % Open alignMagnetVideo.mlapp
            app.pdlg = uiprogressdlg(app.eyeCalibrationUIFigure, ...
                'Title','Aligning Data...');
            app.AlignMagnetVideoApp = alignMagnetVideo(app, app.vars);
        end

        % Button pushed function: DesaccadeDataButton
        function DesaccadeDataButtonPushed(app, event)
            app.pdlg = uiprogressdlg(app.eyeCalibrationUIFigure, ...
                'Title','De-saccading Data...');
            app.DesaccadeScalingFactorsApp = desaccadeScalingFactors(app, app.vars);
        end

        % Value changed function: Magnet1CheckBox
        function Magnet1CheckBoxValueChanged(app, event)
            setAlignPlotsVisibility(app);
        end

        % Value changed function: Magnet2CheckBox
        function Magnet2CheckBoxValueChanged(app, event)
            setAlignPlotsVisibility(app);
        end

        % Value changed function: BadFramesListBox
        function BadFramesListBoxValueChanged(app, event)
            frame = round(str2double(app.BadFramesListBox.Value));
            scrollbarMoved_APP(app.UIAxesTab1PupilPos.Children(end-1), ...
                               [frame -100;frame 100], ...
                               app, app.trackParams, app.frameData);
        end

        % Button pushed function: OverwriteBestChannelButton
        function OverwriteBestChannelButtonPushed(app, event)
            freq = app.vars.stimFreq;
            scaleCh1_Original = app.vars.mag1.vel_scale;
            scaleCh2_Original = app.vars.mag2.vel_scale;
            if app.Magnet1Button.Value
                scaleCh1 = scaleCh1_Original;
                scaleCh2 = 0.0;
                app.vars.vid.vel_amp = app.vars.vid.vel1_amp;
                app.vars.vid.vel_fit = app.vars.vid.vel1_fit;
                app.vars.vid.vel_fitr2 = app.vars.vid.vel1_fitr2;
                app.vars.vid.saccades_all = app.vars.mag1.saccades_all;
            else
                scaleCh1 = 0.0;
                scaleCh2 = scaleCh2_Original;
                app.vars.vid.vel_amp = app.vars.vid.vel2_amp;
                app.vars.vid.vel_fit = app.vars.vid.vel2_fit;
                app.vars.vid.vel_fitr2 = app.vars.vid.vel2_fitr2;
                app.vars.vid.saccades_all = app.vars.mag2.saccades_all;
            end
            % Magnet 1
            mag1Amp = app.vars.mag1.vel_amp;
            r2mag1 = app.vars.mag1.vel_fitr2;
            threshMagChan1 = app.vars.mag1.saccadeThresh;
            mag1Phase = app.vars.mag1.vel_phase;
            % Magnet 2
            mag2Amp = app.vars.mag2.vel_amp;
            r2mag2 = app.vars.mag2.vel_fitr2;
            threshMagChan2 = app.vars.mag2.saccadeThresh;
            mag2Phase = app.vars.mag2.vel_phase;
            % Video
            vidAmp = app.vars.vid.vel_amp;
            r2vid = app.vars.vid.vel_fitr2;
            threshVid = app.vars.vid.saccadeThresh;
            % Save data
            [~, filenameroot] = fileparts(cd);
            save(fullfile(cd, [filenameroot,'.mat']), ...
                 'scaleCh1', 'scaleCh2',...
                 'vidAmp', 'mag1Amp', 'mag1Phase', 'mag2Amp', 'mag2Phase',...
                 'r2mag1', 'r2mag2', 'r2vid', ...
                 'threshVid', 'threshMagChan1', 'threshMagChan2', ...
                 'freq');
            app.vars = plotAlignedTraces_APP(app, app.vars);
        end

        % Button pushed function: CalibrationAnalysisPlotsButton
        function CalibrationAnalysisPlotsButtonPushed(app, event)
            app.vars = saveplotLinearityAnalysis_APP(app, app.vars);
            switch app.vars.vid.bestMagChannel
                case 1
                    app.Magnet1Button.Value = 1;
                case 2
                    app.Magnet2Button.Value = 1;
            end
            uialert(app.eyeCalibrationUIFigure, ...
                'Calibration analysis plots have been generated and saved.', ...
                'Done', ...
                'Icon','success');
        end

        % Value changed function: RigNumberDropDown
        function RigNumberDropDownValueChanged(app, event)
            value = app.RigNumberDropDown.Value;
            app.RigNumberDropDown.Value = readwriteRigNumber(value, 0);
        end

        % Button pushed function: DebugButton
        function DebugButtonPushed(app, event)
            if ~isempty(app.CustomFrameInteger.Value)
                frame = app.CustomFrameInteger.Value;
            else
                frame = str2double(app.BadFramesListBox.Value);
            end
            % Open manualFrameRedo.mlapp
            app.pdlg = uiprogressdlg(app.eyeCalibrationUIFigure, ...
                'Title','Generating Ground Truth Frame...');
            saveGroundTruthFrame(app.vars, app.trackParams, frame);
            close(app.pdlg);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.eyeCalibrationUIFigure = uifigure('Visible', 'off');
            app.eyeCalibrationUIFigure.Name = 'eyeCalibration v2.0';
            app.eyeCalibrationUIFigure.CloseRequestFcn = createCallbackFcn(app, @eyeCalibrationUIFigureCloseRequest, true);
            app.GridLayoutTabGroup = uigridlayout(app.eyeCalibrationUIFigure);
            app.TabGroup = uitabgroup(app.GridLayoutTabGroup);
            app.EyeTrackingTab1 = uitab(app.TabGroup);
            app.EyeTrackingTab1.Title = 'Eye Tracking';
            app.GridLayoutTab1Rows = uigridlayout(app.EyeTrackingTab1);
            app.UIAxesTab1PupilPos = uiaxes(app.GridLayoutTab1Rows);
            app.UIAxesTab1PupilPos.XTick = [];
            app.UIAxesTab1PupilPos.XTickLabel = {};
            app.UIAxesTab1PupilPos.YTick = [];
            app.UIAxesTab1PupilPos.YTickLabel = {};
            app.UIAxesTab1PupilPos.ZTick = [];
            app.UIAxesTab1PupilPos.ZTickLabel = '';
            app.UIAxesTab1CRPos = uiaxes(app.GridLayoutTab1Rows);
            app.UIAxesTab1CRPos.XTick = [];
            app.UIAxesTab1CRPos.XTickLabel = '';
            app.UIAxesTab1CRPos.YTick = [];
            app.UIAxesTab1CRPos.YTickLabel = '';
            app.UIAxesTab1CRPos.ZTick = [];
            app.UIAxesTab1CRPos.ZTickLabel = '';
            app.UIAxesTab1Radii = uiaxes(app.GridLayoutTab1Rows);
            app.UIAxesTab1Radii.XTick = [];
            app.UIAxesTab1Radii.XTickLabel = '';
            app.UIAxesTab1Radii.YTick = [];
            app.UIAxesTab1Radii.YTickLabel = '';
            app.UIAxesTab1Radii.ZTick = [];
            app.UIAxesTab1Radii.ZTickLabel = '';
            app.GridLayoutTab1FolderRow = uigridlayout(app.GridLayoutTab1Rows);
            app.FolderButtonTab1 = uibutton(app.GridLayoutTab1FolderRow, 'push');
            app.FolderButtonTab1.ButtonPushedFcn = createCallbackFcn(app, @FolderButtonTab1Pushed, true);
            app.FolderButtonTab1.Text = 'Open Folder...';
            app.FolderTextTab1 = uieditfield(app.GridLayoutTab1FolderRow, 'text');
            app.FolderTextTab1.ValueChangedFcn = createCallbackFcn(app, @FolderTextTab1ValueChanged, true);
            app.GridLayoutTab1MainRow = uigridlayout(app.GridLayoutTab1Rows);
            app.UIAxesTab1EyeTracking = uiaxes(app.GridLayoutTab1MainRow);
            app.UIAxesTab1EyeTracking.XTick = [];
            app.UIAxesTab1EyeTracking.XTickLabel = {};
            app.UIAxesTab1EyeTracking.YTick = [];
            app.UIAxesTab1EyeTracking.YTickLabel = {};
            app.UIAxesTab1EyeTracking.ZTick = [];
            app.UIAxesTab1EyeTracking.ZTickLabel = '';
            app.GridLayoutTab1ParametersPanel = uigridlayout(app.GridLayoutTab1MainRow);
            app.CameraButtonGroup = uibuttongroup(app.GridLayoutTab1ParametersPanel);
            app.CameraButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CameraButtonGroupSelectionChanged, true);
            app.CameraButtonGroup.Title = 'Camera';
            app.Camera1Button = uiradiobutton(app.CameraButtonGroup);
            app.Camera1Button.Text = '1 (Incomplete)';
            app.Camera1Button.Value = true;
            app.Camera2Button = uiradiobutton(app.CameraButtonGroup);
            app.Camera2Button.Text = '2 (Incomplete)';
            app.RunEyeTrackingButton = uibutton(app.GridLayoutTab1ParametersPanel, 'push');
            app.RunEyeTrackingButton.ButtonPushedFcn = createCallbackFcn(app, @RunEyeTrackingButtonPushed, true);
            app.RunEyeTrackingButton.Enable = 'off';
            app.RunEyeTrackingButton.Text = {'Run'; 'Eye Tracking'};
            app.SetupEyeTrackingButton = uibutton(app.GridLayoutTab1ParametersPanel, 'push');
            app.SetupEyeTrackingButton.ButtonPushedFcn = createCallbackFcn(app, @SetupEyeTrackingButtonPushed, true);
            app.SetupEyeTrackingButton.Text = {'Setup'; 'Eye Tracking'};
            app.ManualFrameReDosPanel = uipanel(app.GridLayoutTab1MainRow);
            app.ManualFrameReDosPanel.Title = 'Manual Frame Re-Dos';
            app.GridLayoutTab1RedosPanel = uigridlayout(app.ManualFrameReDosPanel);
            app.RedoCustomFrameButton = uibutton(app.GridLayoutTab1RedosPanel, 'push');
            app.RedoCustomFrameButton.ButtonPushedFcn = createCallbackFcn(app, @RedoCustomFrameButtonPushed, true);
            app.RedoCustomFrameButton.Enable = 'off';
            app.RedoCustomFrameButton.Text = {'Redo Custom'; 'Frame'};
            app.RedoSelectedFrameButton = uibutton(app.GridLayoutTab1RedosPanel, 'push');
            app.RedoSelectedFrameButton.ButtonPushedFcn = createCallbackFcn(app, @RedoSelectedFrameButtonPushed, true);
            app.RedoSelectedFrameButton.Enable = 'off';
            app.RedoSelectedFrameButton.Text = {'Redo Selected'; 'Frame'};
            app.BadFramesListBox = uilistbox(app.GridLayoutTab1RedosPanel);
            app.BadFramesListBox.Items = {'None'};
            app.BadFramesListBox.ValueChangedFcn = createCallbackFcn(app, @BadFramesListBoxValueChanged, true);
            app.BadFramesListBox.Value = 'None';
            app.GridLayoutCustomFrame = uigridlayout(app.GridLayoutTab1RedosPanel);
            app.CustomFrameInteger = uieditfield(app.GridLayoutCustomFrame, 'numeric');
            app.CustomFrameInteger.Limits = [1 Inf];
            app.CustomFrameInteger.RoundFractionalValues = 'on';
            app.CustomFrameInteger.AllowEmpty = 'on';
            app.CustomFrameInteger.Value = [];
            app.CustomFrameLabel = uilabel(app.GridLayoutCustomFrame);
            app.CustomFrameLabel.Text = 'Custom Frame';
            app.DebugButton = uibutton(app.GridLayoutCustomFrame, 'push');
            app.DebugButton.ButtonPushedFcn = createCallbackFcn(app, @DebugButtonPushed, true);
            app.DebugButton.Text = 'Debug';
            app.EyeAnalysisTab2 = uitab(app.TabGroup);
            app.EyeAnalysisTab2.Title = 'Eye Analysis';
            app.GridLayoutTab2Rows = uigridlayout(app.EyeAnalysisTab2);
            app.UIAxesAlignedPositions = uiaxes(app.GridLayoutTab2Rows);
            ylabel(app.UIAxesAlignedPositions, 'Y')
            app.UIAxesAlignedVelocities = uiaxes(app.GridLayoutTab2Rows);
            xlabel(app.UIAxesAlignedVelocities, 'X')
            ylabel(app.UIAxesAlignedVelocities, 'Y')
            app.GridLayoutTab2MainRow = uigridlayout(app.GridLayoutTab2Rows);
            app.AlignMagnetandVideoDataPanel = uipanel(app.GridLayoutTab2MainRow);
            app.AlignMagnetandVideoDataPanel.Title = 'STEP 1: Align Magnet and Video Data';
            app.AlignDataButton = uibutton(app.AlignMagnetandVideoDataPanel, 'push');
            app.AlignDataButton.ButtonPushedFcn = createCallbackFcn(app, @AlignDataButtonPushed, true);
            app.AlignDataButton.Enable = 'off';
            app.AlignDataButton.Text = 'Align Data';
            app.Magnet1CheckBox = uicheckbox(app.AlignMagnetandVideoDataPanel);
            app.Magnet1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Magnet1CheckBoxValueChanged, true);
            app.Magnet1CheckBox.Enable = 'off';
            app.Magnet1CheckBox.Text = 'Magnet 1';
            app.Magnet1CheckBox.Value = true;
            app.Magnet2CheckBox = uicheckbox(app.AlignMagnetandVideoDataPanel);
            app.Magnet2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Magnet2CheckBoxValueChanged, true);
            app.Magnet2CheckBox.Enable = 'off';
            app.Magnet2CheckBox.Text = 'Magnet 2';
            app.Magnet2CheckBox.Value = true;
            app.DesaccadeforScalingFactorsPanel = uipanel(app.GridLayoutTab2MainRow);
            app.DesaccadeforScalingFactorsPanel.Title = 'STEP 2: Desaccade for Scaling Factors';
            app.GridLayout2 = uigridlayout(app.DesaccadeforScalingFactorsPanel);
            app.DesaccadeDataButton = uibutton(app.GridLayout2, 'push');
            app.DesaccadeDataButton.ButtonPushedFcn = createCallbackFcn(app, @DesaccadeDataButtonPushed, true);
            app.DesaccadeDataButton.Enable = 'off';
            app.DesaccadeDataButton.Text = 'Desaccade Data';
            app.CalibrationQualityAnalysisPanel = uipanel(app.GridLayoutTab2MainRow);
            app.CalibrationQualityAnalysisPanel.Title = 'STEP 3: Analysis of Calibration Quality';
            app.GridLayout3 = uigridlayout(app.CalibrationQualityAnalysisPanel);
            app.CalibrationAnalysisPlotsButton = uibutton(app.GridLayout3, 'push');
            app.CalibrationAnalysisPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrationAnalysisPlotsButtonPushed, true);
            app.CalibrationAnalysisPlotsButton.Enable = 'off';
            app.CalibrationAnalysisPlotsButton.Text = 'Calibration Analysis Plots';
            app.GridLayoutTab2ParametersPanel = uigridlayout(app.GridLayoutTab2MainRow);
            app.CameraTimeStampsDropDown = uidropdown(app.GridLayoutTab2ParametersPanel);
            app.CameraTimeStampsDropDown.Items = {'Camera 1', 'Camera 2', 'Median'};
            app.CameraTimeStampsDropDown.Value = 'Camera 2';
            app.EyeMagnetLocationDropDown = uidropdown(app.GridLayoutTab2ParametersPanel);
            app.EyeMagnetLocationDropDown.Items = {'Left', 'Right'};
            app.EyeMagnetLocationDropDown.Value = 'Left';
            app.StimulusFrequencyLabel = uilabel(app.GridLayoutTab2ParametersPanel);
            app.StimulusFrequencyLabel.Text = 'Stimulus Frequency';
            app.CameraTimeStampsLabel = uilabel(app.GridLayoutTab2ParametersPanel);
            app.CameraTimeStampsLabel.Text = 'Camera Time Stamps';
            app.EyeMagnetLocationLabel = uilabel(app.GridLayoutTab2ParametersPanel);
            app.EyeMagnetLocationLabel.Text = 'Eye Magnet Location';
            app.GridLayoutStimulusFrequencyEditField = uigridlayout(app.GridLayoutTab2ParametersPanel);
            app.StimulusFrequencyEditField = uieditfield(app.GridLayoutStimulusFrequencyEditField, 'numeric');
            app.StimulusFrequencyEditField.Limits = [0.001 Inf];
            app.StimulusFrequencyEditField.Value = 1;
            app.RigNumberLabel = uilabel(app.GridLayoutTab2ParametersPanel);
            app.RigNumberLabel.Text = 'Rig Number';
            app.RigNumberDropDown = uidropdown(app.GridLayoutTab2ParametersPanel);
            app.RigNumberDropDown.Items = {'None', 'D241A', 'D241B', 'D243A', 'D243B', 'D253'};
            app.RigNumberDropDown.ValueChangedFcn = createCallbackFcn(app, @RigNumberDropDownValueChanged, true);
            app.RigNumberDropDown.Value = 'None';
            app.GridLayout = uigridlayout(app.GridLayoutTab2MainRow);
            app.BestChannelButtonGroup = uibuttongroup(app.GridLayout);
            app.BestChannelButtonGroup.Enable = 'off';
            app.BestChannelButtonGroup.Title = 'Best Channel';
            app.Magnet1Button = uiradiobutton(app.BestChannelButtonGroup);
            app.Magnet1Button.Enable = 'off';
            app.Magnet1Button.Text = 'Magnet 1';
            app.Magnet1Button.Value = true;
            app.Magnet2Button = uiradiobutton(app.BestChannelButtonGroup);
            app.Magnet2Button.Enable = 'off';
            app.Magnet2Button.Text = 'Magnet 2';
            app.OverwriteBestChannelButton = uibutton(app.GridLayout, 'push');
            app.OverwriteBestChannelButton.ButtonPushedFcn = createCallbackFcn(app, @OverwriteBestChannelButtonPushed, true);
            app.OverwriteBestChannelButton.Enable = 'off';
            app.OverwriteBestChannelButton.Text = {'Overwrite'; 'Best Channel'};
            app.FolderTextTab2 = uieditfield(app.GridLayoutTab2Rows, 'text');
            app.FolderTextTab2.Editable = 'off';
            % Show the figure after all components are created
            app.eyeCalibrationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = eyeCalAnalysis(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.eyeCalibrationUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.eyeCalibrationUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.eyeCalibrationUIFigure)
        end
    end
end