classdef alignMagnetVideo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AlignMagnetVideoUIFigure      matlab.ui.Figure
        GridLayoutRows                matlab.ui.container.GridLayout
        GridLayoutControlsRow         matlab.ui.container.GridLayout
        GridLayoutControlsPanel       matlab.ui.container.GridLayout
        ControlsPanel                 matlab.ui.container.Panel
        FlipVideoButton               matlab.ui.control.Button
        FlipMagnet2Button             matlab.ui.control.Button
        FlipMagnet1Button             matlab.ui.control.Button
        alignMagnetChannelScale       matlab.ui.control.NumericEditField
        MagnetChannelScaleLabel       matlab.ui.control.Label
        CutoffFrequencyEditField      matlab.ui.control.NumericEditField
        LPFCutoffFrequencyEditFieldLabel  matlab.ui.control.Label
        VideoCheckBox                 matlab.ui.control.CheckBox
        Magnet2CheckBox               matlab.ui.control.CheckBox
        Magnet1CheckBox               matlab.ui.control.CheckBox
        AlignmentMethodDropDown       matlab.ui.control.DropDown
        AlignmentMethodDropDownLabel  matlab.ui.control.Label
        GridLayoutParametersButtonGroup  matlab.ui.container.GridLayout
        ParametersButtonGroup         matlab.ui.container.ButtonGroup
        CustomValueEditField          matlab.ui.control.NumericEditField
        ShiftValueLabel4              matlab.ui.control.Label
        ShiftValueLabel3              matlab.ui.control.Label
        ShiftValueLabel2              matlab.ui.control.Label
        ShiftValueLabel1              matlab.ui.control.Label
        CustomButton                  matlab.ui.control.ToggleButton
        Magnet2VelocityButton         matlab.ui.control.ToggleButton
        Magnet2PositionButton         matlab.ui.control.ToggleButton
        Magnet1VelocityButton         matlab.ui.control.ToggleButton
        Magnet1PositionButton         matlab.ui.control.ToggleButton
        UIAxesAlignedVelocityCycles   matlab.ui.control.UIAxes
        UIAxesAlignedVelocities       matlab.ui.control.UIAxes
        UIAxesAlignedPositions        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ParentApp; % Main app
        vars;
    end
    
    methods (Access = private)
        
        function [vars] = updateShiftValues(app, vars)
            % Update shift value labels
            app.ShiftValueLabel1.Text = num2str(vars.mag1.pos_linearity_maxr2Loc);
            app.ShiftValueLabel2.Text = num2str(vars.mag1.vel_linearity_maxr2Loc);
            app.ShiftValueLabel3.Text = num2str(vars.mag2.pos_linearity_maxr2Loc);
            app.ShiftValueLabel4.Text = num2str(vars.mag2.vel_linearity_maxr2Loc);           
             % Assign the selected alignment value
            if app.Magnet1PositionButton.Value
                vars.chosenShiftVal = str2double(app.ShiftValueLabel1.Text);
            elseif app.Magnet1VelocityButton.Value
                vars.chosenShiftVal = str2double(app.ShiftValueLabel2.Text);
            elseif app.Magnet2PositionButton.Value
                vars.chosenShiftVal = str2double(app.ShiftValueLabel3.Text);
            elseif app.Magnet2VelocityButton.Value
                vars.chosenShiftVal = str2double(app.ShiftValueLabel4.Text);
            else 
                vars.chosenShiftVal = app.CustomValueEditField.Value;
            end
        end

        function updatePlotVisibility(app)
            % Ensure something is already plotted
            if isempty(app.UIAxesAlignedPositions.Children)
                return
            end
            % Change visibility based on checkbox states
            if app.Magnet1CheckBox.Value
                app.UIAxesAlignedPositions.Children(2).Visible = "on";
                app.UIAxesAlignedVelocities.Children(2).Visible = "on";
                app.UIAxesAlignedVelocityCycles.Children(2).Visible = "on";
                app.FlipMagnet1Button.Enable = "on";
            else
                app.UIAxesAlignedPositions.Children(2).Visible = "off";
                app.UIAxesAlignedVelocities.Children(2).Visible = "off";
                app.UIAxesAlignedVelocityCycles.Children(2).Visible = "off";
                app.FlipMagnet1Button.Enable = "off";
            end
            if app.Magnet2CheckBox.Value
                app.UIAxesAlignedPositions.Children(1).Visible = "on";
                app.UIAxesAlignedVelocities.Children(1).Visible = "on";
                app.UIAxesAlignedVelocityCycles.Children(1).Visible = "on";
                app.FlipMagnet2Button.Enable = "on";
            else
                app.UIAxesAlignedPositions.Children(1).Visible = "off";
                app.UIAxesAlignedVelocities.Children(1).Visible = "off";
                app.UIAxesAlignedVelocityCycles.Children(1).Visible = "off";
                app.FlipMagnet2Button.Enable = "off";
            end
            if app.VideoCheckBox.Value
                app.UIAxesAlignedPositions.Children(3).Visible = "on";
                app.UIAxesAlignedVelocities.Children(3).Visible = "on";
                app.UIAxesAlignedVelocityCycles.Children(3).Visible = "on";
            else
                app.UIAxesAlignedPositions.Children(3).Visible = "off";
                app.UIAxesAlignedVelocities.Children(3).Visible = "off";
                app.UIAxesAlignedVelocityCycles.Children(3).Visible = "off";
            end
        end

        function updateAllValuesPlots(app)
            % Display a progress bar
            pbar = uiprogressdlg(app.AlignMagnetVideoUIFigure, ...
                'Title','Please Wait', ...
                'Message','Calculating shift values...');
            pbar.Value = 0.4;
            % Calculate alignment shift values using selected method
            if app.AlignmentMethodDropDown.Value == "Linearity"
                app.vars = alignMagnetVideo_Linearity_APP(app, app.vars);
            end
            pbar.Value = 0.7;
            % Update shift values
            app.vars = updateShiftValues(app, app.vars);
            pbar.Value = 0.9;
            % Plot aligned position and velocity traces
            app.vars = plotAlignedTraces_APP(app, app.vars);
            updatePlotVisibility(app);
            % Close progress bar
            pbar.Value = 0.97;
            pause(0.01);
            close(pbar);
        end

        function updatePlotsOnly(app)
            % Update shift values
            app.vars = updateShiftValues(app, app.vars);
            % Plot aligned position and velocity traces
            app.vars = plotAlignedTraces_APP(app, app.vars);
            updatePlotVisibility(app);
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentapp, vars)
            app.ParentApp = parentapp;
            app.vars = vars;
            app.vars.alignCutoffFrequency = app.CutoffFrequencyEditField.Value;
            app.vars.alignMagnetChannelScale = app.alignMagnetChannelScale.Value;
            if isfield(app.vars, 'chosenShiftVal') && (app.vars.chosenShiftVal ~= 0)
                app.CustomValueEditField.Value = app.vars.chosenShiftVal;
            end
            updateAllValuesPlots(app);
        end

        % Close request function: AlignMagnetVideoUIFigure
        function AlignMagnetVideoUIFigureCloseRequest(app, event)
            try
                selection = uiconfirm(app.AlignMagnetVideoUIFigure, ...
                    'Upon closing this window, would you like to overwrite any existing alignment data with these new values?', ...
                    'Overwrite Existing Alignment', ...
                    'Options',["Yes","No","Cancel"], ...
                    'DefaultOption',1, ...
                    'CancelOption',3);
                switch selection
                    case "Yes"
                        zoom(app.UIAxesAlignedPositions, 'out');
                        zoom(app.UIAxesAlignedVelocities, 'out');
                        zoom(app.UIAxesAlignedVelocityCycles, 'out');
                        app.vars = saveplotMagnetVideoAlignment_APP(app, app.vars);
                        closeAlignMagnetVideoApp(app.ParentApp, app.vars, 1);
                    case "No"
                        closeAlignMagnetVideoApp(app.ParentApp, app.vars, 0);
                    case "Cancel"
                        return
                end
            catch msgid
                fprintf('Error upon attempting to close Align Magnet Video App:\n');
                disp(msgid.message);
                delete(app)
            end
        end

        % Value changed function: Magnet1CheckBox
        function Magnet1CheckBoxValueChanged(app, event)
            updatePlotVisibility(app);
        end

        % Value changed function: Magnet2CheckBox
        function Magnet2CheckBoxValueChanged(app, event)
            updatePlotVisibility(app);
        end

        % Value changed function: VideoCheckBox
        function VideoCheckBoxValueChanged(app, event)
            updatePlotVisibility(app);
        end

        % Button pushed function: FlipMagnet1Button
        function FlipMagnet1ButtonPushed(app, event)
            % Check to see if something is already plotted
            if isempty(app.UIAxesAlignedPositions.Children)
                return
            end
            app.vars.mag1.align_sign = app.vars.mag1.align_sign * -1;
            if isfield(app.vars.mag1, 'vel_scale')
                app.vars.mag1.vel_scale = app.vars.mag1.vel_scale * -1;
            end
            mag1 = app.vars.mag1;
            [~, filenameroot] = fileparts(cd);
            save(fullfile(cd, [filenameroot '_analysis.mat']), 'mag1', '-append');
            updateAllValuesPlots(app);
            updatePlotVisibility(app);
        end

        % Button pushed function: FlipMagnet2Button
        function FlipMagnet2ButtonPushed(app, event)
            % Check to see if something is already plotted
            if isempty(app.UIAxesAlignedPositions.Children)
                return
            end
            app.vars.mag2.align_sign = app.vars.mag2.align_sign * -1;
            if isfield(app.vars.mag2, 'vel_scale')
                app.vars.mag2.vel_scale = app.vars.mag2.vel_scale * -1;
            end
            mag2 = app.vars.mag2;
            [~, filenameroot] = fileparts(cd);
            save(fullfile(cd, [filenameroot '_analysis.mat']), 'mag2', '-append');
            updateAllValuesPlots(app);
            updatePlotVisibility(app);
        end

        % Value changed function: alignMagnetChannelScale
        function alignMagnetChannelScaleValueChanged(app, event)
            app.vars.alignMagnetChannelScale = app.alignMagnetChannelScale.Value;
            updateAllValuesPlots(app);
            updatePlotVisibility(app);
        end

        % Value changed function: CutoffFrequencyEditField
        function CutoffFrequencyEditFieldValueChanged(app, event)
            app.vars.alignCutoffFrequency = app.CutoffFrequencyEditField.Value;
            updateAllValuesPlots(app);
            updatePlotVisibility(app);
        end

        % Selection changed function: ParametersButtonGroup
        function ParametersButtonGroupSelectionChanged(app, event)
            updatePlotsOnly(app);
            updatePlotVisibility(app);
        end

        % Value changed function: CustomValueEditField
        function CustomValueEditFieldValueChanged(app, event)
            app.CustomButton.Value = 1;
            app.vars.chosenShiftVal = app.CustomValueEditField.Value;
            pause(0.01);
            updatePlotsOnly(app);
            updatePlotVisibility(app);
        end

        % Button pushed function: FlipVideoButton
        function FlipVideoButtonPushed(app, event)
            % Check to see if something is already plotted
            if isempty(app.UIAxesAlignedPositions.Children)
                return
            end
            app.vars.vid.align_sign = app.vars.vid.align_sign * -1;
            vid = app.vars.vid;
            [~, filenameroot] = fileparts(cd);
            save(fullfile(cd, [filenameroot '_analysis.mat']), 'vid', '-append');
            updateAllValuesPlots(app);
            updatePlotVisibility(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AlignMagnetVideoUIFigure = uifigure('Visible', 'off');
            app.AlignMagnetVideoUIFigure.Name = 'Align Magnet Video';
            app.AlignMagnetVideoUIFigure.CloseRequestFcn = createCallbackFcn(app, @AlignMagnetVideoUIFigureCloseRequest, true);
            app.GridLayoutRows = uigridlayout(app.AlignMagnetVideoUIFigure);
            app.UIAxesAlignedPositions = uiaxes(app.GridLayoutRows);
            ylabel(app.UIAxesAlignedPositions, 'Y')
            zlabel(app.UIAxesAlignedPositions, 'Z')
            app.UIAxesAlignedVelocities = uiaxes(app.GridLayoutRows);
            xlabel(app.UIAxesAlignedVelocities, 'X')
            ylabel(app.UIAxesAlignedVelocities, 'Y')
            zlabel(app.UIAxesAlignedVelocities, 'Z')
            app.GridLayoutControlsRow = uigridlayout(app.GridLayoutRows);
            app.UIAxesAlignedVelocityCycles = uiaxes(app.GridLayoutControlsRow);
            xlabel(app.UIAxesAlignedVelocityCycles, 'X')
            ylabel(app.UIAxesAlignedVelocityCycles, 'Y')
            zlabel(app.UIAxesAlignedVelocityCycles, 'Z')
            app.GridLayoutParametersButtonGroup = uigridlayout(app.GridLayoutControlsRow);
            app.ParametersButtonGroup = uibuttongroup(app.GridLayoutParametersButtonGroup);
            app.ParametersButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ParametersButtonGroupSelectionChanged, true);
            app.Magnet1PositionButton = uitogglebutton(app.ParametersButtonGroup);
            app.Magnet1PositionButton.Text = 'Magnet 1 - Position';
            app.Magnet1VelocityButton = uitogglebutton(app.ParametersButtonGroup);
            app.Magnet1VelocityButton.Text = 'Magnet 1 - Velocity';
            app.Magnet2PositionButton = uitogglebutton(app.ParametersButtonGroup);
            app.Magnet2PositionButton.Text = 'Magnet 2 - Position';
            app.Magnet2VelocityButton = uitogglebutton(app.ParametersButtonGroup);
            app.Magnet2VelocityButton.Text = 'Magnet 2 - Velocity';
            app.CustomButton = uitogglebutton(app.ParametersButtonGroup);
            app.CustomButton.Text = 'Custom';
            app.CustomButton.Value = true;
            app.ShiftValueLabel1 = uilabel(app.ParametersButtonGroup);
            app.ShiftValueLabel1.Text = '(Shift Value)';
            app.ShiftValueLabel2 = uilabel(app.ParametersButtonGroup);
            app.ShiftValueLabel2.Text = '(Shift Value)';
            app.ShiftValueLabel3 = uilabel(app.ParametersButtonGroup);
            app.ShiftValueLabel3.Text = '(Shift Value)';
            app.ShiftValueLabel4 = uilabel(app.ParametersButtonGroup);
            app.ShiftValueLabel4.Text = '(Shift Value)';
            app.CustomValueEditField = uieditfield(app.ParametersButtonGroup, 'numeric');
            app.CustomValueEditField.ValueDisplayFormat = '%.0f';
            app.CustomValueEditField.ValueChangedFcn = createCallbackFcn(app, @CustomValueEditFieldValueChanged, true);
            app.GridLayoutControlsPanel = uigridlayout(app.GridLayoutControlsRow);
            app.ControlsPanel = uipanel(app.GridLayoutControlsPanel);
            app.AlignmentMethodDropDownLabel = uilabel(app.ControlsPanel);
            app.AlignmentMethodDropDownLabel.Text = 'Alignment Method';
            app.AlignmentMethodDropDown = uidropdown(app.ControlsPanel);
            app.AlignmentMethodDropDown.Items = {'Linearity'};
            app.AlignmentMethodDropDown.Value = 'Linearity';
            app.Magnet1CheckBox = uicheckbox(app.ControlsPanel);
            app.Magnet1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Magnet1CheckBoxValueChanged, true);
            app.Magnet1CheckBox.Text = 'Magnet 1';
            app.Magnet1CheckBox.Value = true;
            app.Magnet2CheckBox = uicheckbox(app.ControlsPanel);
            app.Magnet2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Magnet2CheckBoxValueChanged, true);
            app.Magnet2CheckBox.Text = 'Magnet 2';
            app.Magnet2CheckBox.Value = true;
            app.VideoCheckBox = uicheckbox(app.ControlsPanel);
            app.VideoCheckBox.ValueChangedFcn = createCallbackFcn(app, @VideoCheckBoxValueChanged, true);
            app.VideoCheckBox.Text = 'Video';
            app.VideoCheckBox.Value = true;
            app.LPFCutoffFrequencyEditFieldLabel = uilabel(app.ControlsPanel);
            app.LPFCutoffFrequencyEditFieldLabel.Text = 'LPF Cutoff Frequency';
            app.CutoffFrequencyEditField = uieditfield(app.ControlsPanel, 'numeric');
            app.CutoffFrequencyEditField.Limits = [0 Inf];
            app.CutoffFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @CutoffFrequencyEditFieldValueChanged, true);
            app.CutoffFrequencyEditField.Value = 15;
            app.MagnetChannelScaleLabel = uilabel(app.ControlsPanel);
            app.MagnetChannelScaleLabel.Text = 'Magnet Channel Scale';
            app.alignMagnetChannelScale = uieditfield(app.ControlsPanel, 'numeric');
            app.alignMagnetChannelScale.Limits = [0 Inf];
            app.alignMagnetChannelScale.ValueChangedFcn = createCallbackFcn(app, @alignMagnetChannelScaleValueChanged, true);
            app.alignMagnetChannelScale.Value = 35;
            app.FlipMagnet1Button = uibutton(app.ControlsPanel, 'push');
            app.FlipMagnet1Button.ButtonPushedFcn = createCallbackFcn(app, @FlipMagnet1ButtonPushed, true);
            app.FlipMagnet1Button.Text = 'Flip Magnet 1';
            app.FlipMagnet2Button = uibutton(app.ControlsPanel, 'push');
            app.FlipMagnet2Button.ButtonPushedFcn = createCallbackFcn(app, @FlipMagnet2ButtonPushed, true);
            app.FlipMagnet2Button.Text = 'Flip Magnet 2';
            app.FlipVideoButton = uibutton(app.ControlsPanel, 'push');
            app.FlipVideoButton.ButtonPushedFcn = createCallbackFcn(app, @FlipVideoButtonPushed, true);
            app.FlipVideoButton.Text = 'Flip Video';
            % Show the figure after all components are created
            app.AlignMagnetVideoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = alignMagnetVideo(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AlignMagnetVideoUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.AlignMagnetVideoUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AlignMagnetVideoUIFigure)
        end
    end
end