classdef desaccadeScalingFactors < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DesaccadeforScalingFactorsUIFigure  matlab.ui.Figure
        GridLayoutGeneralUI             matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        MagnetChannel1Tab               matlab.ui.container.Tab
        GridLayoutMag1ChannelRows       matlab.ui.container.GridLayout
        DesaccadeParametersPanel        matlab.ui.container.Panel
        GridLayoutParameters            matlab.ui.container.GridLayout
        Mag1ResetButton                 matlab.ui.control.Button
        Mag1ManualAddButton             matlab.ui.control.Button
        Mag1ThresholdEditField          matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel         matlab.ui.control.Label
        CutoffFrequencyEditField        matlab.ui.control.NumericEditField
        MinimumGoodDataLengthEditField  matlab.ui.control.NumericEditField
        DesaccadeMethodLabel            matlab.ui.control.Label
        SaccadeWindow1EditField         matlab.ui.control.NumericEditField
        SaccadeWindow2EditField         matlab.ui.control.NumericEditField
        DesaccadeMethodDropDown         matlab.ui.control.DropDown
        MinimumGoodDataLengthLabel      matlab.ui.control.Label
        SaccadeWindowmsLabel            matlab.ui.control.Label
        LPFCutoffFrequencyLabel         matlab.ui.control.Label
        UIAxesMag1Position              matlab.ui.control.UIAxes
        UIAxesMag1Velocity              matlab.ui.control.UIAxes
        UIAxesMag1Threshold             matlab.ui.control.UIAxes
        MagnetChannel2Tab               matlab.ui.container.Tab
        GridLayoutMag2ChannelRows       matlab.ui.container.GridLayout
        DesaccadeMag2ParametersPanel    matlab.ui.container.Panel
        GridLayoutMag2Parameters        matlab.ui.container.GridLayout
        Mag2ResetButton                 matlab.ui.control.Button
        Mag2ManualAddButton             matlab.ui.control.Button
        Mag2ThresholdEditField          matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel_2       matlab.ui.control.Label
        Mag2SaccadeWindow2EditField     matlab.ui.control.NumericEditField
        DesaccadeMethodDropDown_2Label  matlab.ui.control.Label
        Mag2DesaccadeMethodDropDown     matlab.ui.control.DropDown
        Mag2MinimumGoodDataLengthEditField  matlab.ui.control.NumericEditField
        MinimumGoodDataLengthLabel_2    matlab.ui.control.Label
        Mag2SaccadeWindow1EditField     matlab.ui.control.NumericEditField
        SaccadeWindowmsLabel_2          matlab.ui.control.Label
        Mag2CutoffFrequencyEditField    matlab.ui.control.NumericEditField
        LPFCutoffFrequencyLabel_2       matlab.ui.control.Label
        UIAxesMag2Velocity              matlab.ui.control.UIAxes
        UIAxesMag2Position              matlab.ui.control.UIAxes
        UIAxesMag2Threshold             matlab.ui.control.UIAxes
        VideoChannelTab                 matlab.ui.container.Tab
        GridLayoutVidChannelRows        matlab.ui.container.GridLayout
        DesaccadeVidParametersPanel     matlab.ui.container.Panel
        GridLayoutVidParameters         matlab.ui.container.GridLayout
        VidResetButton                  matlab.ui.control.Button
        VidManualAddButton              matlab.ui.control.Button
        VidThresholdEditField           matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel_3       matlab.ui.control.Label
        VidSaccadeWindow2EditField      matlab.ui.control.NumericEditField
        DesaccadeMethodDropDown_2Label_2  matlab.ui.control.Label
        VidDesaccadeMethodDropDown      matlab.ui.control.DropDown
        VidMinimumGoodDataLengthEditField  matlab.ui.control.NumericEditField
        MinimumGoodDataLengthLabel_3    matlab.ui.control.Label
        VidSaccadeWindow1EditField      matlab.ui.control.NumericEditField
        SaccadeWindowmsLabel_3          matlab.ui.control.Label
        VidCutoffFrequencyEditField     matlab.ui.control.NumericEditField
        LPFCutoffFrequencyLabel_3       matlab.ui.control.Label
        UIAxesVidVelocity               matlab.ui.control.UIAxes
        UIAxesVidPosition               matlab.ui.control.UIAxes
        UIAxesVidThreshold              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ParentApp; % Main app
        vars;
    end
    
    methods (Access = private)
        
        function updateAllSaccadePlots(app, updateOnly)
            if ~exist('updateOnly', 'var') || ~updateOnly
                updateOnly = 0;
                app.vars = desaccadePreprocessing_APP(app, app.vars);
            end
            app.vars = desaccadeMagnetVideo_APP(app, app.vars);
            app.vars = plotDesaccadedTraces_APP(app, app.vars, updateOnly);
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentapp, vars)
            app.ParentApp = parentapp;
            app.vars = vars;
            app.vars.desaccadeCutoffFrequency = app.CutoffFrequencyEditField.Value;
            updateAllSaccadePlots(app);
        end

        % Close request function: DesaccadeforScalingFactorsUIFigure
        function DesaccadeforScalingFactorsUIFigureCloseRequest(app, event)
            try
                selection = uiconfirm(app.DesaccadeforScalingFactorsUIFigure, ...
                    'Upon closing this window, would you like to overwrite any existing desaccading with these new settings?', ...
                    'Overwrite Existing Desaccading', ...
                    'Options',["Yes","No","Cancel"], ...
                    'DefaultOption',1, ...
                    'CancelOption',3);
                switch selection
                    case "Yes"
                        zoom(app.UIAxesMag1Position, 'out');
                        zoom(app.UIAxesMag1Velocity, 'out');
                        zoom(app.UIAxesMag1Threshold, 'out');
                        zoom(app.UIAxesMag2Position, 'out');
                        zoom(app.UIAxesMag2Velocity, 'out');
                        zoom(app.UIAxesMag2Threshold, 'out');
                        zoom(app.UIAxesVidPosition, 'out');
                        zoom(app.UIAxesVidVelocity, 'out');
                        zoom(app.UIAxesVidThreshold, 'out');
                        app.vars = saveplotSummaryDesaccading_APP(app, app.vars);
                        closeDesaccadeScalingFactorsApp(app.ParentApp, app.vars, 1);
                    case "No"
                        closeDesaccadeScalingFactorsApp(app.ParentApp, app.vars, 0);
                    case "Cancel"
                        return
                end
            catch msgid
                fprintf('Error upon attempting to close Desaccading for Scaling Factors App:\n');
                disp(msgid.message);
                delete(app)
            end
        end

        % Value changed function: Mag1ThresholdEditField
        function Mag1ThresholdEditFieldValueChanged(app, event)
            updateAllSaccadePlots(app, 1);
        end

        % Value changed function: Mag2ThresholdEditField
        function Mag2ThresholdEditFieldValueChanged(app, event)
            updateAllSaccadePlots(app, 1);
        end

        % Value changed function: VidThresholdEditField
        function VidThresholdEditFieldValueChanged(app, event)
            updateAllSaccadePlots(app, 1);
        end

        % Value changed function: CutoffFrequencyEditField
        function CutoffFrequencyEditFieldValueChanged(app, event)
            app.Mag2CutoffFrequencyEditField.Value = app.CutoffFrequencyEditField.Value;
            app.VidCutoffFrequencyEditField.Value = app.CutoffFrequencyEditField.Value;
            app.vars.desaccadeCutoffFrequency = app.CutoffFrequencyEditField.Value;
            updateAllSaccadePlots(app);
        end

        % Value changed function: MinimumGoodDataLengthEditField
        function MinimumGoodDataLengthEditFieldValueChanged(app, event)
            app.Mag2MinimumGoodDataLengthEditField.Value = app.MinimumGoodDataLengthEditField.Value;
            app.VidMinimumGoodDataLengthEditField.Value = app.MinimumGoodDataLengthEditField.Value;
            updateAllSaccadePlots(app, 1);
        end

        % Value changed function: SaccadeWindow1EditField
        function SaccadeWindow1EditFieldValueChanged(app, event)
            app.Mag2SaccadeWindow1EditField.Value = app.SaccadeWindow1EditField.Value;
            app.VidSaccadeWindow1EditField.Value = app.SaccadeWindow1EditField.Value;
            updateAllSaccadePlots(app, 1);
        end

        % Value changed function: DesaccadeMethodDropDown
        function DesaccadeMethodDropDownValueChanged(app, event)
            app.Mag2DesaccadeMethodDropDown.Value = app.DesaccadeMethodDropDown.Value;
            app.VidDesaccadeMethodDropDown.Value = app.DesaccadeMethodDropDown.Value;
            if app.DesaccadeMethodDropDown.Value == "Squared Velocity Threshold"
                app.Mag1ThresholdEditField.Value = round(0.1*std(app.vars.mag1.vel_data_aligned.^2));
                app.Mag2ThresholdEditField.Value = round(0.1*std(app.vars.mag2.vel_data_aligned.^2));
                app.VidThresholdEditField.Value  = round(0.1*std(app.vars.vid.vel_data_upsampled_aligned.^2));
                app.UIAxesMag1Threshold.YLabel.String = 'Squared Velocity (V2/s2)';
                app.UIAxesMag2Threshold.YLabel.String = 'Squared Velocity (V2/s2)';
                app.UIAxesVidThreshold.YLabel.String = 'Squared Velocity (V2/s2)';
            elseif app.DesaccadeMethodDropDown.Value == "Moving Median MAD"
                app.Mag1ThresholdEditField.Value = 7;
                app.Mag2ThresholdEditField.Value = 7;
                app.VidThresholdEditField.Value = 7;
                app.UIAxesMag1Threshold.YLabel.String = '|Velocity - median(Velocity)| (V/s)';
                app.UIAxesMag2Threshold.YLabel.String = '|Velocity - median(Velocity)| (V/s)';
                app.UIAxesVidThreshold.YLabel.String = '|Velocity - median(Velocity)| (V/s)';
            else
                error('"'+app.DesaccadeMethodDropDown.Value+'" is an invalid desaccading option.');
            end
            updateAllSaccadePlots(app, 0);
        end

        % Value changed function: SaccadeWindow2EditField
        function SaccadeWindow2EditFieldValueChanged(app, event)
            app.Mag2SaccadeWindow2EditField.Value = app.SaccadeWindow2EditField.Value;
            app.VidSaccadeWindow2EditField.Value = app.SaccadeWindow2EditField.Value;
            updateAllSaccadePlots(app, 1);
        end

        % Button pushed function: Mag1ManualAddButton
        function Mag1ManualAddButtonPushed(app, event)
            desacLine = drawline(app.UIAxesMag1Position, ...
              'Color','r', ...
              'InteractionsAllowed','none');
            selection = uiconfirm(app.DesaccadeforScalingFactorsUIFigure, ...
                'Would you like to set the selected segment as a saccade?', ...
                'Confirm Manual Desaccade', ...
                'Options',["Yes","Cancel"], ...
                'DefaultOption',1, ...
                'CancelOption',2);
            switch selection
                case "Yes"
                    app.vars = manualDesaccade_APP(app.vars, "mag1", sort(desacLine.Position));
                    delete(desacLine);
                    app.Mag1ResetButton.Enable = "on";
                    updateAllSaccadePlots(app, 1);
                case "Cancel"
                    delete(desacLine);
            end
        end

        % Button pushed function: Mag2ManualAddButton
        function Mag2ManualAddButtonPushed(app, event)
            desacLine = drawline(app.UIAxesMag2Position, ...
              'Color','r', ...
              'InteractionsAllowed','none');
            selection = uiconfirm(app.DesaccadeforScalingFactorsUIFigure, ...
                'Would you like to set the selected segment as a saccade?', ...
                'Confirm Manual Desaccade', ...
                'Options',["Yes","Cancel"], ...
                'DefaultOption',1, ...
                'CancelOption',2);
            switch selection
                case "Yes"
                    app.vars = manualDesaccade_APP(app.vars, "mag2", sort(desacLine.Position));
                    delete(desacLine);
                    app.Mag2ResetButton.Enable = "on";
                    updateAllSaccadePlots(app, 1);
                case "Cancel"
                    delete(desacLine);
            end
        end

        % Button pushed function: VidManualAddButton
        function VidManualAddButtonPushed(app, event)
            desacLine = drawline(app.UIAxesVidPosition, ...
              'Color','r', ...
              'InteractionsAllowed','none');
            selection = uiconfirm(app.DesaccadeforScalingFactorsUIFigure, ...
                'Would you like to set the selected segment as a saccade?', ...
                'Confirm Manual Desaccade', ...
                'Options',["Yes","Cancel"], ...
                'DefaultOption',1, ...
                'CancelOption',2);
            switch selection
                case "Yes"
                    app.vars = manualDesaccade_APP(app.vars, "vid", sort(desacLine.Position));
                    delete(desacLine);
                    app.VidResetButton.Enable = "on";
                    updateAllSaccadePlots(app, 1);
                case "Cancel"
                    delete(desacLine);
            end
        end

        % Button pushed function: Mag1ResetButton
        function Mag1ResetButtonPushed(app, event)
            app.vars = manualDesaccade_APP(app.vars, "mag1", "reset");
            app.Mag1ResetButton.Enable = "off";
            updateAllSaccadePlots(app, 1);
        end

        % Button pushed function: Mag2ResetButton
        function Mag2ResetButtonPushed(app, event)
            app.vars = manualDesaccade_APP(app.vars, "mag2", "reset");
            app.Mag2ResetButton.Enable = "off";
            updateAllSaccadePlots(app, 1);
        end

        % Button pushed function: VidResetButton
        function VidResetButtonPushed(app, event)
            app.vars = manualDesaccade_APP(app.vars, "vid", "reset");
            app.VidResetButton.Enable = "off";
            updateAllSaccadePlots(app, 1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DesaccadeforScalingFactorsUIFigure = uifigure('Visible', 'off');
            app.DesaccadeforScalingFactorsUIFigure.Name = 'Desaccade for Scaling Factors';
            app.DesaccadeforScalingFactorsUIFigure.CloseRequestFcn = createCallbackFcn(app, @DesaccadeforScalingFactorsUIFigureCloseRequest, true);
            app.GridLayoutGeneralUI = uigridlayout(app.DesaccadeforScalingFactorsUIFigure);
            app.TabGroup = uitabgroup(app.GridLayoutGeneralUI);
            app.MagnetChannel1Tab = uitab(app.TabGroup);
            app.MagnetChannel1Tab.Title = 'Magnet Channel 1';
            app.GridLayoutMag1ChannelRows = uigridlayout(app.MagnetChannel1Tab);
            app.UIAxesMag1Threshold = uiaxes(app.GridLayoutMag1ChannelRows);
            ylabel(app.UIAxesMag1Threshold, 'Y')
            app.UIAxesMag1Velocity = uiaxes(app.GridLayoutMag1ChannelRows);
            xlabel(app.UIAxesMag1Velocity, 'X')
            ylabel(app.UIAxesMag1Velocity, 'Y')
            app.UIAxesMag1Position = uiaxes(app.GridLayoutMag1ChannelRows);
            ylabel(app.UIAxesMag1Position, 'Y')
            app.DesaccadeParametersPanel = uipanel(app.GridLayoutMag1ChannelRows);
            app.GridLayoutParameters = uigridlayout(app.DesaccadeParametersPanel);
            app.LPFCutoffFrequencyLabel = uilabel(app.GridLayoutParameters);
            app.LPFCutoffFrequencyLabel.Text = 'Cutoff Frequency ';
            app.SaccadeWindowmsLabel = uilabel(app.GridLayoutParameters);
            app.SaccadeWindowmsLabel.Text = 'Saccade Window ';
            app.MinimumGoodDataLengthLabel = uilabel(app.GridLayoutParameters);
            app.MinimumGoodDataLengthLabel.Text = 'Min. Good Length';
            app.DesaccadeMethodDropDown = uidropdown(app.GridLayoutParameters);
            app.DesaccadeMethodDropDown.Items = {'Squared Velocity Threshold', 'Moving Median MAD'};
            app.DesaccadeMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @DesaccadeMethodDropDownValueChanged, true);
            app.DesaccadeMethodDropDown.Value = 'Moving Median MAD';
            app.SaccadeWindow2EditField = uieditfield(app.GridLayoutParameters, 'numeric');
            app.SaccadeWindow2EditField.Limits = [1 Inf];
            app.SaccadeWindow2EditField.ValueChangedFcn = createCallbackFcn(app, @SaccadeWindow2EditFieldValueChanged, true);
            app.SaccadeWindow2EditField.Value = 50;
            app.SaccadeWindow1EditField = uieditfield(app.GridLayoutParameters, 'numeric');
            app.SaccadeWindow1EditField.Limits = [1 Inf];
            app.SaccadeWindow1EditField.ValueChangedFcn = createCallbackFcn(app, @SaccadeWindow1EditFieldValueChanged, true);
            app.SaccadeWindow1EditField.Value = 50;
            app.DesaccadeMethodLabel = uilabel(app.GridLayoutParameters);
            app.DesaccadeMethodLabel.Text = 'Desaccade Method ';
            app.MinimumGoodDataLengthEditField = uieditfield(app.GridLayoutParameters, 'numeric');
            app.MinimumGoodDataLengthEditField.Limits = [1 Inf];
            app.MinimumGoodDataLengthEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumGoodDataLengthEditFieldValueChanged, true);
            app.MinimumGoodDataLengthEditField.Value = 500;
            app.CutoffFrequencyEditField = uieditfield(app.GridLayoutParameters, 'numeric');
            app.CutoffFrequencyEditField.Limits = [0 Inf];
            app.CutoffFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @CutoffFrequencyEditFieldValueChanged, true);
            app.CutoffFrequencyEditField.Value = 15;
            app.ThresholdEditFieldLabel = uilabel(app.GridLayoutParameters);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.Mag1ThresholdEditField = uieditfield(app.GridLayoutParameters, 'numeric');
            app.Mag1ThresholdEditField.Limits = [0 Inf];
            app.Mag1ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @Mag1ThresholdEditFieldValueChanged, true);
            app.Mag1ThresholdEditField.Value = 6;
            app.Mag1ManualAddButton = uibutton(app.GridLayoutParameters, 'push');
            app.Mag1ManualAddButton.ButtonPushedFcn = createCallbackFcn(app, @Mag1ManualAddButtonPushed, true);
            app.Mag1ManualAddButton.Text = 'Manual Add';
            app.Mag1ResetButton = uibutton(app.GridLayoutParameters, 'push');
            app.Mag1ResetButton.ButtonPushedFcn = createCallbackFcn(app, @Mag1ResetButtonPushed, true);
            app.Mag1ResetButton.Enable = 'off';
            app.Mag1ResetButton.Text = 'Reset';
            app.MagnetChannel2Tab = uitab(app.TabGroup);
            app.MagnetChannel2Tab.Title = 'Magnet Channel 2';
            app.GridLayoutMag2ChannelRows = uigridlayout(app.MagnetChannel2Tab);
            app.UIAxesMag2Threshold = uiaxes(app.GridLayoutMag2ChannelRows);
            ylabel(app.UIAxesMag2Threshold, 'Y')
            app.UIAxesMag2Position = uiaxes(app.GridLayoutMag2ChannelRows);
            ylabel(app.UIAxesMag2Position, 'Y')
            app.UIAxesMag2Velocity = uiaxes(app.GridLayoutMag2ChannelRows);
            xlabel(app.UIAxesMag2Velocity, 'X')
            ylabel(app.UIAxesMag2Velocity, 'Y')
            app.DesaccadeMag2ParametersPanel = uipanel(app.GridLayoutMag2ChannelRows);
            app.GridLayoutMag2Parameters = uigridlayout(app.DesaccadeMag2ParametersPanel);
            app.LPFCutoffFrequencyLabel_2 = uilabel(app.GridLayoutMag2Parameters);
            app.LPFCutoffFrequencyLabel_2.Enable = 'off';
            app.LPFCutoffFrequencyLabel_2.Text = 'LPF Cutoff Frequency ';
            app.Mag2CutoffFrequencyEditField = uieditfield(app.GridLayoutMag2Parameters, 'numeric');
            app.Mag2CutoffFrequencyEditField.Limits = [0 Inf];
            app.Mag2CutoffFrequencyEditField.Editable = 'off';
            app.Mag2CutoffFrequencyEditField.Enable = 'off';
            app.Mag2CutoffFrequencyEditField.Value = 15;
            app.SaccadeWindowmsLabel_2 = uilabel(app.GridLayoutMag2Parameters);
            app.SaccadeWindowmsLabel_2.Enable = 'off';
            app.SaccadeWindowmsLabel_2.Text = 'Saccade Window (ms) ';
            app.Mag2SaccadeWindow1EditField = uieditfield(app.GridLayoutMag2Parameters, 'numeric');
            app.Mag2SaccadeWindow1EditField.Limits = [1 Inf];
            app.Mag2SaccadeWindow1EditField.Editable = 'off';
            app.Mag2SaccadeWindow1EditField.Enable = 'off';
            app.Mag2SaccadeWindow1EditField.Value = 50;
            app.MinimumGoodDataLengthLabel_2 = uilabel(app.GridLayoutMag2Parameters);
            app.MinimumGoodDataLengthLabel_2.Enable = 'off';
            app.MinimumGoodDataLengthLabel_2.Text = 'Min. Good Data Length (ms) ';
            app.Mag2MinimumGoodDataLengthEditField = uieditfield(app.GridLayoutMag2Parameters, 'numeric');
            app.Mag2MinimumGoodDataLengthEditField.Limits = [1 Inf];
            app.Mag2MinimumGoodDataLengthEditField.Editable = 'off';
            app.Mag2MinimumGoodDataLengthEditField.Enable = 'off';
            app.Mag2MinimumGoodDataLengthEditField.Value = 500;
            app.Mag2DesaccadeMethodDropDown = uidropdown(app.GridLayoutMag2Parameters);
            app.Mag2DesaccadeMethodDropDown.Items = {'Squared Velocity Threshold', 'Moving Median MAD'};
            app.Mag2DesaccadeMethodDropDown.Enable = 'off';
            app.Mag2DesaccadeMethodDropDown.Value = 'Moving Median MAD';
            app.DesaccadeMethodDropDown_2Label = uilabel(app.GridLayoutMag2Parameters);
            app.DesaccadeMethodDropDown_2Label.Enable = 'off';
            app.DesaccadeMethodDropDown_2Label.Text = 'Desaccade Method ';
            app.Mag2SaccadeWindow2EditField = uieditfield(app.GridLayoutMag2Parameters, 'numeric');
            app.Mag2SaccadeWindow2EditField.Limits = [1 Inf];
            app.Mag2SaccadeWindow2EditField.Editable = 'off';
            app.Mag2SaccadeWindow2EditField.Enable = 'off';
            app.Mag2SaccadeWindow2EditField.Value = 50;
            app.ThresholdEditFieldLabel_2 = uilabel(app.GridLayoutMag2Parameters);
            app.ThresholdEditFieldLabel_2.Text = 'Threshold';
            app.Mag2ThresholdEditField = uieditfield(app.GridLayoutMag2Parameters, 'numeric');
            app.Mag2ThresholdEditField.Limits = [0 Inf];
            app.Mag2ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @Mag2ThresholdEditFieldValueChanged, true);
            app.Mag2ThresholdEditField.Value = 6;
            app.Mag2ManualAddButton = uibutton(app.GridLayoutMag2Parameters, 'push');
            app.Mag2ManualAddButton.ButtonPushedFcn = createCallbackFcn(app, @Mag2ManualAddButtonPushed, true);
            app.Mag2ManualAddButton.Text = 'Manual Add';
            app.Mag2ResetButton = uibutton(app.GridLayoutMag2Parameters, 'push');
            app.Mag2ResetButton.ButtonPushedFcn = createCallbackFcn(app, @Mag2ResetButtonPushed, true);
            app.Mag2ResetButton.Enable = 'off';
            app.Mag2ResetButton.Text = 'Reset';
            app.VideoChannelTab = uitab(app.TabGroup);
            app.VideoChannelTab.Title = 'Video Channel';
            app.GridLayoutVidChannelRows = uigridlayout(app.VideoChannelTab);
            app.UIAxesVidThreshold = uiaxes(app.GridLayoutVidChannelRows);
            ylabel(app.UIAxesVidThreshold, 'Y')
            app.UIAxesVidPosition = uiaxes(app.GridLayoutVidChannelRows);
            ylabel(app.UIAxesVidPosition, 'Y')
            app.UIAxesVidVelocity = uiaxes(app.GridLayoutVidChannelRows);
            xlabel(app.UIAxesVidVelocity, 'X')
            ylabel(app.UIAxesVidVelocity, 'Y')
            app.DesaccadeVidParametersPanel = uipanel(app.GridLayoutVidChannelRows);
            app.GridLayoutVidParameters = uigridlayout(app.DesaccadeVidParametersPanel);
            app.LPFCutoffFrequencyLabel_3 = uilabel(app.GridLayoutVidParameters);
            app.LPFCutoffFrequencyLabel_3.Enable = 'off';
            app.LPFCutoffFrequencyLabel_3.Text = 'LPF Cutoff Frequency ';
            app.VidCutoffFrequencyEditField = uieditfield(app.GridLayoutVidParameters, 'numeric');
            app.VidCutoffFrequencyEditField.Limits = [0 Inf];
            app.VidCutoffFrequencyEditField.Editable = 'off';
            app.VidCutoffFrequencyEditField.Enable = 'off';
            app.VidCutoffFrequencyEditField.Value = 15;
            app.SaccadeWindowmsLabel_3 = uilabel(app.GridLayoutVidParameters);
            app.SaccadeWindowmsLabel_3.Enable = 'off';
            app.SaccadeWindowmsLabel_3.Text = 'Saccade Window (ms) ';
            app.VidSaccadeWindow1EditField = uieditfield(app.GridLayoutVidParameters, 'numeric');
            app.VidSaccadeWindow1EditField.Limits = [1 Inf];
            app.VidSaccadeWindow1EditField.Editable = 'off';
            app.VidSaccadeWindow1EditField.Enable = 'off';
            app.VidSaccadeWindow1EditField.Value = 50;
            app.MinimumGoodDataLengthLabel_3 = uilabel(app.GridLayoutVidParameters);
            app.MinimumGoodDataLengthLabel_3.Enable = 'off';
            app.MinimumGoodDataLengthLabel_3.Text = 'Min. Good Data Length (ms) ';
            app.VidMinimumGoodDataLengthEditField = uieditfield(app.GridLayoutVidParameters, 'numeric');
            app.VidMinimumGoodDataLengthEditField.Limits = [1 Inf];
            app.VidMinimumGoodDataLengthEditField.Editable = 'off';
            app.VidMinimumGoodDataLengthEditField.Enable = 'off';
            app.VidMinimumGoodDataLengthEditField.Value = 500;
            app.VidDesaccadeMethodDropDown = uidropdown(app.GridLayoutVidParameters);
            app.VidDesaccadeMethodDropDown.Items = {'Squared Velocity Threshold', 'Moving Median MAD'};
            app.VidDesaccadeMethodDropDown.Enable = 'off';
            app.VidDesaccadeMethodDropDown.Value = 'Moving Median MAD';
            app.DesaccadeMethodDropDown_2Label_2 = uilabel(app.GridLayoutVidParameters);
            app.DesaccadeMethodDropDown_2Label_2.Enable = 'off';
            app.DesaccadeMethodDropDown_2Label_2.Text = 'Desaccade Method ';
            app.VidSaccadeWindow2EditField = uieditfield(app.GridLayoutVidParameters, 'numeric');
            app.VidSaccadeWindow2EditField.Limits = [1 Inf];
            app.VidSaccadeWindow2EditField.Editable = 'off';
            app.VidSaccadeWindow2EditField.Enable = 'off';
            app.VidSaccadeWindow2EditField.Value = 50;
            app.ThresholdEditFieldLabel_3 = uilabel(app.GridLayoutVidParameters);
            app.ThresholdEditFieldLabel_3.Text = 'Threshold';
            app.VidThresholdEditField = uieditfield(app.GridLayoutVidParameters, 'numeric');
            app.VidThresholdEditField.Limits = [0 Inf];
            app.VidThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @VidThresholdEditFieldValueChanged, true);
            app.VidThresholdEditField.Value = 6;
            app.VidManualAddButton = uibutton(app.GridLayoutVidParameters, 'push');
            app.VidManualAddButton.ButtonPushedFcn = createCallbackFcn(app, @VidManualAddButtonPushed, true);
            app.VidManualAddButton.Text = 'Manual Add';
            app.VidResetButton = uibutton(app.GridLayoutVidParameters, 'push');
            app.VidResetButton.ButtonPushedFcn = createCallbackFcn(app, @VidResetButtonPushed, true);
            app.VidResetButton.Enable = 'off';
            app.VidResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.DesaccadeforScalingFactorsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = desaccadeScalingFactors(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.DesaccadeforScalingFactorsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.DesaccadeforScalingFactorsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DesaccadeforScalingFactorsUIFigure)
        end
    end
end