classdef setupEyeTracking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SetupEyeTrackingUIFigure       matlab.ui.Figure
        GridLayoutTabGroup             matlab.ui.container.GridLayout
        GridLayoutPanelTimeBar         matlab.ui.container.GridLayout
        PanelTimeBar                   matlab.ui.container.Panel
        GridLayoutTimeBar              matlab.ui.container.GridLayout
        FrameNumberLabel               matlab.ui.control.Label
        TimeBarSlider                  matlab.ui.control.Slider
        TabGroup                       matlab.ui.container.TabGroup
        SelectPupilCenterandROIsTab    matlab.ui.container.Tab
        GridLayoutTab1Rows             matlab.ui.container.GridLayout
        GridLayoutTab1InstructionsRow  matlab.ui.container.GridLayout
        Tab1InstructionsText           matlab.ui.control.Label
        Tab1InstructionsBorder         matlab.ui.container.Panel
        GridLayoutTab1PlotRow          matlab.ui.container.GridLayout
        GridLayoutTab1ToolsColumn      matlab.ui.container.GridLayout
        ToolSelectionButtonGroup       matlab.ui.container.ButtonGroup
        RightCRMarkerButton            matlab.ui.control.ToggleButton
        PupilCenterButton              matlab.ui.control.ToggleButton
        ShowAllButton                  matlab.ui.control.ToggleButton
        LeftCRMarkerButton             matlab.ui.control.ToggleButton
        PupilROIButton                 matlab.ui.control.ToggleButton
        NoneButton                     matlab.ui.control.ToggleButton
        UIAxesTab1CenterROIs           matlab.ui.control.UIAxes
        AdjustContrastforPupilTab      matlab.ui.container.Tab
        GridLayoutTab2Rows             matlab.ui.container.GridLayout
        Tab2SliderRowBorder            matlab.ui.container.Panel
        GridLayoutTab2SliderRow        matlab.ui.container.GridLayout
        GridLayoutTab2SpotMaskingRow   matlab.ui.container.GridLayout
        PupilRadiusLimits2EditField    matlab.ui.control.NumericEditField
        PupilRadiusLimits1EditField    matlab.ui.control.NumericEditField
        RadiusLimitsLabel              matlab.ui.control.Label
        EdgeFeatureThresholdEditField  matlab.ui.control.NumericEditField
        EdgeFeatureThresholdEditFieldLabel  matlab.ui.control.Label
        MinimumFeaturesEditField       matlab.ui.control.NumericEditField
        ColorbarLimitLabel             matlab.ui.control.Label
        SpotMaskRadiusEditField        matlab.ui.control.NumericEditField
        SpotMaskRadiusLabel            matlab.ui.control.Label
        RemoveBrightSpotsCheckBox      matlab.ui.control.CheckBox
        Tab2SliderValuesText           matlab.ui.control.Label
        Tab2ContrastAdjustmentSlider   matlab.ui.control.RangeSlider
        ContrastAdjustmentblackpointwhitepointLabel  matlab.ui.control.Label
        GridLayoutTab2PlotRow          matlab.ui.container.GridLayout
        UIAxesTab2ContrastDifferenceTitle  matlab.ui.control.Label
        UIAxesTab2ContrastGrayscaleTitle  matlab.ui.control.Label
        UIAxesTab2ContrastGrayscale    matlab.ui.control.UIAxes
        UIAxesTab2ContrastDifference   matlab.ui.control.UIAxes
        GridLayoutTab2InstructionsRow  matlab.ui.container.GridLayout
        Tab2InstructionsText           matlab.ui.control.Label
        Tab2InstructionsBorder         matlab.ui.container.Panel
        AdjustContrastforCRsTab        matlab.ui.container.Tab
        GridLayoutTab3Rows             matlab.ui.container.GridLayout
        Tab3SliderRowBorder            matlab.ui.container.Panel
        GridLayoutTab3SliderRow        matlab.ui.container.GridLayout
        GridLayoutTab3CRParamRow3      matlab.ui.container.GridLayout
        GridLayoutTab3CRParamRow2      matlab.ui.container.GridLayout
        CRRadiusLimits1EditField       matlab.ui.control.NumericEditField
        RadiusLimitsLabel_2            matlab.ui.control.Label
        CRRadiusLimits2EditField       matlab.ui.control.NumericEditField
        GridLayoutTab3CRParamRow1      matlab.ui.container.GridLayout
        VCenterPenaltyEditField        matlab.ui.control.NumericEditField
        RadiusLimitsLabel_3            matlab.ui.control.Label
        CostFunctionThreshEditField    matlab.ui.control.NumericEditField
        CostFunctionThreshLabel        matlab.ui.control.Label
        TopHatDiskRadiusEditField      matlab.ui.control.NumericEditField
        TopHatDiskRadiusLabel          matlab.ui.control.Label
        GridLayoutTab3PlotRow          matlab.ui.container.GridLayout
        UIAxesTab3ContrastGrayscaleTitle  matlab.ui.control.Label
        UIAxesTab3ContrastHoughTitle   matlab.ui.control.Label
        UIAxesTab3ContrastHough        matlab.ui.control.UIAxes
        UIAxesTab3ContrastGrayscale    matlab.ui.control.UIAxes
        GridLayoutTab3InstructionsRow  matlab.ui.container.GridLayout
        Tab3InstructionsText           matlab.ui.control.Label
        Tab3InstructionsBorder         matlab.ui.container.Panel
    end

    
    properties (Access = private)
        ParentApp; % Main app
    end
    
    properties (Access = public)
        vars;
        trackParams;
        data;
    end
    
    methods (Access = private)
        
        function [vars, result] = areAllTasksCompleted(~, vars)
            result = 1;
            result = result * vars.pupilCenterCompleted;
            result = result * vars.pupilROICompleted;
            result = result * vars.leftCRMarkerCompleted;
            result = result * vars.rightCRMarkerCompleted;
        end

        function [vars, data] = setRoiVisibility(~, vars, data, status)
            try
                data.pupilCenterPlot.Visible = status(1);
            catch
                vars.pupilCenterCompleted = 0;
            end
            try 
                data.pupilROIPlot.Visible = status(2);
            catch
                vars.pupilROICompleted = 0;
            end
            try
                data.leftCRMarkerPlot.Visible = status(3);
            catch
                vars.leftCRMarkerCompleted = 0;
            end
            try
                data.rightCRMarkerPlot.Visible = status(4);
            catch
                vars.rightCRMarkerCompleted = 0;
            end
        end

        function se = getDiskStrel(app, radius)
            key = sprintf('r%d', round(radius));
            if ~isfield(app.data.strelCache, key)
                app.data.strelCache.(key) = strel('disk', round(radius));
            end
            se = app.data.strelCache.(key);
        end

        function h = plotcircle(~, target, x, y, r, color)
                th = 0:pi/50:2*pi;
                xunit = r * cos(th) + x;
                yunit = r * sin(th) + y;
                h = plot(target, xunit, yunit, color, 'LineWidth',2);
        end

        function [vars, trackParams, data] = updateTab1TrackParams(app, vars, trackParams, data)
            vars.frame = round(app.TimeBarSlider.Value);
            pupilStart = data.pupilCenterPlot.Position;
            trackParams.pupilStart = [round(pupilStart(1)), round(pupilStart(2))];
            trackParams.maskPupil = createMask(data.pupilROIPlot);
            data.pupilROIPlot.SemiAxes = data.pupilROIPlot.SemiAxes - 3;
            trackParams.maskPupil2 = createMask(app.data.pupilROIPlot);
            % Extract CR marker positions for v4 pipeline
            leftCRPos = data.leftCRMarkerPlot.Position;
            rightCRPos = data.rightCRMarkerPlot.Position;
            if strcmp(trackParams.cameraSide, 'left')
                trackParams.priMarker = [round(leftCRPos(1)), round(leftCRPos(2))];
                trackParams.secMarker = [round(rightCRPos(1)), round(rightCRPos(2))];
            else
                trackParams.priMarker = [round(rightCRPos(1)), round(rightCRPos(2))];
                trackParams.secMarker = [round(leftCRPos(1)), round(leftCRPos(2))];
            end
        end

        function [trackParams] = updateTab2TrackParams(app, trackParams)
            trackParams.radiiPupil = [app.PupilRadiusLimits1EditField.Value, app.PupilRadiusLimits2EditField.Value];
            trackParams.edgeThresh = app.EdgeFeatureThresholdEditField.Value;
            trackParams.minfeatures = app.MinimumFeaturesEditField.Value;
            trackParams.spotMaskRadius = app.SpotMaskRadiusEditField.Value;
            trackParams.adjustmentValuesPupil = app.Tab2ContrastAdjustmentSlider.Value;
        end

        function [trackParams] = updateTab3TrackParams(app, trackParams)
            trackParams.radiiCR = [app.CRRadiusLimits1EditField.Value, app.CRRadiusLimits2EditField.Value];
            trackParams.topHatDiskRadius = app.TopHatDiskRadiusEditField.Value;
            trackParams.crTrackParams.vertCenterPenalty = app.VCenterPenaltyEditField.Value;
            trackParams.crTrackParams.qualityGateK = app.CostFunctionThreshEditField.Value;
        end

        function centerMoved(app, ~, ~)
            img = app.data.rawImgStack(:,:,app.vars.frame);
            updateTab2Plots(app, img, app.trackParams);
        end

        function pupilCenterButton(app)
            app.Tab1InstructionsText.FontColor = 'r';
            app.Tab1InstructionsText.Text = sprintf("Left-click on the center of the pupil to place a crosshair at that position. You can move the crosshair's position as desired.");
            app.TimeBarSlider.Value = 1;
            TimeBarSliderValueChanging(app, struct('Value', 1));
            [app.vars, app.data] = setRoiVisibility(app, app.vars, app.data, ["on","off","off","off"]);
            if ~app.vars.pupilCenterCompleted
                % User selects estimated pupil center using mouse
                app.data.pupilCenterPlot = drawcrosshair(app.UIAxesTab1CenterROIs, ...
                    'LineWidth',1, ...
                    'Color','r');
                app.data.diffCenterPlot = drawcrosshair(app.UIAxesTab2ContrastGrayscale, ...
                    'Position',app.data.pupilCenterPlot.Position, ...
                    'LineWidth',1, ...
                    'StripeColor','w', ...
                    'Label','Center-point');
                addlistener(app.data.diffCenterPlot, 'ROIMoved', @app.centerMoved);
                app.PupilROIButton.Enable = "on";
                app.TimeBarSlider.Enable = "on";
                app.vars.pupilCenterCompleted = 1;
            end      
        end

        function pupilROIButton(app)
            app.Tab1InstructionsText.FontColor = 'm';
            app.Tab1InstructionsText.Text = sprintf("Hold left-click and drag an ellipse around the region-of-interest (ROI) for the pupil.\nYou can move, resize, or rotate the drawn ellipse as needed.");
            [app.vars, app.data] = setRoiVisibility(app, app.vars, app.data, ["off","on","off","off"]);
            if ~app.vars.pupilROICompleted
                % User draws pupil ROI ellipse using mouse
                app.data.pupilROIPlot = drawellipse(app.UIAxesTab1CenterROIs, 'Color','m');
                app.LeftCRMarkerButton.Enable = "on";
                app.vars.pupilROICompleted = 1;
            end  
        end

        function leftCRMarkerButton(app)
            app.Tab1InstructionsText.FontColor = [0 0.5 0];
            app.Tab1InstructionsText.Text = sprintf("On the FIRST FRAME, left-click on the LEFT corneal reflection to place a crosshair marker.");
            app.TimeBarSlider.Value = 1;
            TimeBarSliderValueChanging(app, struct('Value', 1));
            [app.vars, app.data] = setRoiVisibility(app, app.vars, app.data, ["off","off","on","off"]);
            if ~app.vars.leftCRMarkerCompleted
                app.data.leftCRMarkerPlot = drawcrosshair(app.UIAxesTab1CenterROIs, ...
                    'LineWidth', 1, 'Color', [0 0.8 0]);
                app.RightCRMarkerButton.Enable = "on";
                app.vars.leftCRMarkerCompleted = 1;
            end            
        end
        
        function rightCRMarkerButton(app)
            app.Tab1InstructionsText.FontColor = [0 0.8 0.8];
            app.Tab1InstructionsText.Text = sprintf("On the FIRST FRAME, left-click on the RIGHT corneal reflection to place a crosshair marker.");
            app.TimeBarSlider.Value = 1;
            TimeBarSliderValueChanging(app, struct('Value', 1));
            [app.vars, app.data] = setRoiVisibility(app, app.vars, app.data, ["off","off","off","on"]);
            if ~app.vars.rightCRMarkerCompleted
                app.data.rightCRMarkerPlot = drawcrosshair(app.UIAxesTab1CenterROIs, ...
                    'LineWidth', 1, 'Color', [0 0.8 0.8]);
                app.ShowAllButton.Enable = "on";
                app.vars.rightCRMarkerCompleted = 1;
            end
        end

        function updateTab2Plots(app, img, trackParams)
            Px = app.data.diffCenterPlot.Position(1); 
            Py = app.data.diffCenterPlot.Position(2); 
            lowhighIn = app.Tab2ContrastAdjustmentSlider.Value;
            app.Tab2SliderValuesText.Text = sprintf(['(',num2str(lowhighIn(1)),', ',num2str(lowhighIn(2)),')']);
            
            % Apply preprocessing
            img = imfilter(img, fspecial('gaussian',3,.5));
            img = imadjust(img, lowhighIn, []);
            
            % Apply ROI mask
            img(~trackParams.maskPupil) = nan;
            newimg = double(img);
            imgDilated = newimg;

            if app.RemoveBrightSpotsCheckBox.Value
                totalMask = newimg > 204;
                totalMaskDilate = imdilate(totalMask, getDiskStrel(app, app.SpotMaskRadiusEditField.Value));
                imgDilated(totalMaskDilate) = NaN;
            end

            % Apply gaussian smoothing to image
            gaussian_smooth_image = @(I, sigma) imfilter(I, fspecial('gaussian', [ceil(2.5*sigma) ceil(2.5*sigma)], sigma), 'symmetric');
            imgSmoothed = gaussian_smooth_image(imgDilated, 3);

            % Compute Radial Differences of image
            imgRadialDiff = computeRadialDifferences(imgSmoothed, Px, Py);

            radiiPupil = [app.PupilRadiusLimits1EditField.Value, app.PupilRadiusLimits2EditField.Value];

            % Detect pupil borders using starburst algorithm
            [epx, epy, ~] = starburst_pupil_contour_detection(imgSmoothed, Px, Py, ...
                                                              app.EdgeFeatureThresholdEditField.Value, ...
                                                              round(radiiPupil), ...
                                                              app.MinimumFeaturesEditField.Value);
            [~, inliers] = fit_ellipse_ransac(epx(:), epy(:), radiiPupil + [-15, 15]);
            epx2 = epx(inliers);
            epy2 = epy(inliers);
            ellipseResult = fit_ellipse(epx2, epy2);
            
            if isempty(ellipseResult.X0_in)
                pupil_x = nan;
                pupil_y = nan;
                pupil_r1 = nan;
                pupil_r2 = nan;
                pupil_angle = nan;
            else
                pupil_x = ellipseResult.X0_in;
                pupil_y = ellipseResult.Y0_in;
                pupil_r1 = ellipseResult.a;
                pupil_r2 = ellipseResult.b;
                pupil_angle = -ellipseResult.phi;
            end

            phi = linspace(0, 2*pi, 100);
            pRadius = max([pupil_r1, pupil_r2]);
            pLx = pupil_r1*cos(phi)*cos(pupil_angle) - ...
                    sin(pupil_angle)*pupil_r2*sin(phi) + ...
                    pupil_x;
            pLy = pupil_r1*cos(phi)*sin(pupil_angle) + ...
                    cos(pupil_angle)*pupil_r2*sin(phi) + ...
                    pupil_y;
            pMx = pupil_x;
            pMy = pupil_y;

            % Update grayscale plot
            set(app.data.tab2GrayscalePlot, 'CData',imgDilated);
            clim(app.UIAxesTab2ContrastDifference, [0, 255]);
            xlim(app.UIAxesTab2ContrastGrayscale, app.data.tab2PlotXLim);
            ylim(app.UIAxesTab2ContrastGrayscale, app.data.tab2PlotYLim);

            % Update radial difference plot
            set(app.data.tab2DifferencePlot, 'CData',imgRadialDiff);
            set(app.data.tab2DifferenceLine, 'XData',pLx, 'YData',pLy);
            set(app.data.tab2DifferenceMarker, 'XData',pMx, 'YData',pMy);
            set(app.data.tab2DifferenceScatter1, 'XData',epx, 'YData',epy);
            set(app.data.tab2DifferenceScatter2, 'XData',epx2, 'YData',epy2);
            app.UIAxesTab2ContrastDifference.Title.String = sprintf('Pupil Radius: %.3f px', pRadius);
            clim(app.UIAxesTab2ContrastDifference, [0, round(app.EdgeFeatureThresholdEditField.Value+1)]);
            xlim(app.UIAxesTab2ContrastDifference, app.data.tab2PlotXLim);
            ylim(app.UIAxesTab2ContrastDifference, app.data.tab2PlotYLim);
        end

        function updateTab3Plots(app, img, trackParams)
            % Top-hat preprocessing (matches v4 detection pipeline)
            imgTopHat = double(imtophat(uint8(img), getDiskStrel(app, trackParams.topHatDiskRadius)));
            
            radiiCR = [app.CRRadiusLimits1EditField.Value, app.CRRadiusLimits2EditField.Value];
            [A, circen, crr] = CircularHough_Grd(imgTopHat, radiiCR, trackParams.CRthresh, trackParams.CRfilter, 1);

            try
                cr1_r = crr(1);
                cr2_r = crr(2);
                crText = {sprintf('CR 1 Radius: %.3f px', cr1_r), ...
                          sprintf('CR 2 Radius: %.3f px', cr2_r)};
    
                cla(app.UIAxesTab3ContrastHough);
    
                % Update grayscale plot
                set(app.data.tab3GrayscalePlot, 'CData',imgTopHat);
                xlim(app.UIAxesTab3ContrastGrayscale, app.data.tab3PlotXLim);
                ylim(app.UIAxesTab3ContrastGrayscale, app.data.tab3PlotYLim);
                
                % Plot Hough-Transformed image and detected circles
                app.data.tab3HoughPlot = imagesc(app.UIAxesTab3ContrastHough, A);
                colormap(app.UIAxesTab3ContrastHough, 'parula');
                hold(app.UIAxesTab3ContrastHough, 'on');
                for i = 1:length(crr)
                    plot(app.UIAxesTab3ContrastHough, circen(i,1), circen(i,2), 'rx', 'LineWidth',3, 'MarkerSize',12);
                    plotcircle(app, app.UIAxesTab3ContrastHough, circen(i,1), circen(i,2), crr(i), 'r');
                end
                title(app.UIAxesTab3ContrastHough, crText, ...
                      'Units','characters', ...
                      'Position',[2, 1, 0], ...
                      'HorizontalAlignment','left', ...
                      'VerticalAlignment','bottom', ...
                      'FontSize',12, ...
                      'BackgroundColor','w', ...
                      'EdgeColor','k');
                hold(app.UIAxesTab3ContrastHough, 'off');
                axis(app.UIAxesTab3ContrastHough, 'image');
                set(app.UIAxesTab3ContrastHough, 'ydir', 'reverse');
                set(app.UIAxesTab3ContrastHough, 'Visible', 'off');
                app.UIAxesTab3ContrastHough.Title.Visible = 'on';
                xlim(app.UIAxesTab3ContrastHough, app.data.tab3PlotXLim);
                ylim(app.UIAxesTab3ContrastHough, app.data.tab3PlotYLim);
    
                linkaxes([app.UIAxesTab3ContrastGrayscale, app.UIAxesTab3ContrastHough], 'xy');
            catch
                disp("Unable to detect both corneal reflections with currently set parameters.");
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentapp, trackParams)
            app.ParentApp = parentapp;
            app.trackParams = trackParams;
            app.vars.frame = 1;  % Initial image frame to test

            % Display a progress bar
            pbar = uiprogressdlg(app.SetupEyeTrackingUIFigure, ...
                'Title','Please Wait', ...
                'Message','Setting up application');
            pbar.Value = .05; 

            % Initialize flag variables
            app.vars.pupilCenterCompleted   = 0;
            app.vars.pupilROICompleted      = 0;
            app.vars.leftCRMarkerCompleted  = 0;
            app.vars.rightCRMarkerCompleted = 0;

            % Load previous parameter values if available
            try
                % Pupil parameters
                app.PupilRadiusLimits1EditField.Value = trackParams.radiiPupil(1);
                app.PupilRadiusLimits2EditField.Value = trackParams.radiiPupil(2);
                app.EdgeFeatureThresholdEditField.Value = trackParams.edgeThresh;
                app.MinimumFeaturesEditField.Value = trackParams.minfeatures;
                app.SpotMaskRadiusEditField.Value = trackParams.spotMaskRadius;
                app.Tab2ContrastAdjustmentSlider.Value = trackParams.adjustmentValuesPupil;
                % CR parameters
                app.CRRadiusLimits1EditField.Value = trackParams.radiiCR(1);
                app.CRRadiusLimits2EditField.Value = trackParams.radiiCR(2);
                app.TopHatDiskRadiusEditField.Value = trackParams.topHatDiskRadius;
                app.CostFunctionThreshEditField.Value = trackParams.crTrackParams.qualityGateK;
                app.VCenterPenaltyEditField.Value = trackParams.crTrackParams.vertCenterPenalty;
            catch
                % Do nothing
            end

            % Set initial tracking parameter values
            % TODO: Find out what these two variables do
            app.trackParams.CRthresh = 10;  % Currently we use the same CRThresh for both CRs, can make different in the future
            app.trackParams.CRfilter = 8;  % Currently we use the same CRFilter for both CRs, can make different in the future

            % CR detection parameters
            app.trackParams.topHatDiskRadius  = app.TopHatDiskRadiusEditField.Value;
            app.trackParams.crMinArea         = 8;
            app.trackParams.crMaxArea         = 800;
            app.trackParams.crMaxEccentricity = 0.85;

            % Cost function weights
            app.trackParams.crTrackParams.w_dist   = 4.0;
            app.trackParams.crTrackParams.w_smooth = 1.0;
            app.trackParams.crTrackParams.w_geom   = 2.0;
            app.trackParams.crTrackParams.w_bright = 1.0;
            
            % Geometric priors
            app.trackParams.crTrackParams.secConeHalfAngle  = 45;
            app.trackParams.crTrackParams.conePenalty       = 1.0;
            app.trackParams.crTrackParams.vertCenterPenalty = app.VCenterPenaltyEditField.Value;
            
            % Pair selection
            app.trackParams.crTrackParams.maxCandidates = 6;
            
            % Quality gate
            app.trackParams.crTrackParams.qualityGateK = app.CostFunctionThreshEditField.Value;

            % Sliding window
            app.trackParams.crTrackParams.slidingWindowSize = 100;
            app.trackParams.crTrackParams.minWindowCount    = 10;

            % Camera side derived from camera number
            if app.trackParams.cam == 1
                app.trackParams.cameraSide = 'left';
            else
                app.trackParams.cameraSide = 'right';
            end
            
            % CR marker positions (set by user on Tab 1)
            app.trackParams.priMarker = [NaN NaN];
            app.trackParams.secMarker = [NaN NaN];
            
            % Import stack of image frames
            pbar.Value = .15; 
            pbar.Message = 'Importing images from .tiff file';
            [app.data.rawImgStack, app.data.nImages] = getImageStack(['img',num2str(app.trackParams.cam),'.tiff']);
            app.TimeBarSlider.Limits = [1, app.data.nImages];

            pbar.Value = 0.4;
            pbar.Message = 'Processing images';
            app.data.rawImgStack = imresize(app.data.rawImgStack, 2);

            [H, W, ~] = size(app.data.rawImgStack);
            [app.trackParams.gridX, app.trackParams.gridY] = meshgrid(1:W, 1:H);
            app.data.strelCache = struct();

            pbar.Value = 0.5;
            img = app.data.rawImgStack(:,:,app.vars.frame);

            pbar.Value = 0.6;
            pbar.Message = 'Preparing and plotting images';
            
            % Clear figures
            cla(app.UIAxesTab1CenterROIs);
            cla(app.UIAxesTab2ContrastGrayscale);
            cla(app.UIAxesTab2ContrastDifference);
            cla(app.UIAxesTab3ContrastGrayscale);
            cla(app.UIAxesTab3ContrastHough);
            
            % Initialize plots
            pbar.Value = 0.7;
            app.data.tab3GrayscalePlot = imagesc(app.UIAxesTab3ContrastGrayscale, img);
            colormap(app.UIAxesTab3ContrastGrayscale, 'gray');
            axis(app.UIAxesTab3ContrastGrayscale, 'image');
            set(app.UIAxesTab3ContrastGrayscale, 'ydir', 'reverse');
            set(app.UIAxesTab3ContrastGrayscale, 'Visible', 'off');

            app.data.tab3HoughPlot = imagesc(app.UIAxesTab3ContrastHough, img);
            colormap(app.UIAxesTab3ContrastHough, 'parula');
            title(app.UIAxesTab3ContrastHough, ' ', ...
                  'Units','characters', ...
                  'Position',[2, 1, 0], ...
                  'HorizontalAlignment','left', ...
                  'VerticalAlignment','bottom', ...
                  'FontSize',12, ...
                  'BackgroundColor','w', ...
                  'EdgeColor','k');
            axis(app.UIAxesTab3ContrastHough, 'image');
            set(app.UIAxesTab3ContrastHough, 'ydir', 'reverse');
            set(app.UIAxesTab3ContrastHough, 'Visible', 'off');
            app.UIAxesTab3ContrastHough.Title.Visible = 'on';

            linkaxes([app.UIAxesTab3ContrastGrayscale, app.UIAxesTab3ContrastHough], 'xy');
            
            pbar.Value = 0.8;
            app.data.tab2GrayscalePlot = imagesc(app.UIAxesTab2ContrastGrayscale, img);
            colormap(app.UIAxesTab2ContrastGrayscale, 'gray');
            axis(app.UIAxesTab2ContrastGrayscale, 'image');
            set(app.UIAxesTab2ContrastGrayscale, 'ydir', 'reverse');
            set(app.UIAxesTab2ContrastGrayscale, 'Visible', 'off');
            
            phi = nan(1,100);
            app.data.tab2DifferencePlot = imagesc(app.UIAxesTab2ContrastDifference, img);
            hold(app.UIAxesTab2ContrastDifference, 'on');
            app.data.tab2DifferenceLine = line(app.UIAxesTab2ContrastDifference, phi, phi, 'Color','r', 'LineWidth',2);
            app.data.tab2DifferenceMarker = plot(app.UIAxesTab2ContrastDifference, nan, nan, '+r', 'LineWidth',2, 'MarkerSize',10);
            app.data.tab2DifferenceScatter1 = plot(app.UIAxesTab2ContrastDifference, [nan,nan], [nan,nan], '.w');
            app.data.tab2DifferenceScatter2 = plot(app.UIAxesTab2ContrastDifference, [nan,nan], [nan,nan], '.w');
            colormap(app.UIAxesTab2ContrastDifference, 'parula'); %'winter'
            colorbar(app.UIAxesTab2ContrastDifference);
            clim(app.UIAxesTab2ContrastDifference, [0, 204]);
            title(app.UIAxesTab2ContrastDifference, ' ', ...
                  'Units','characters', ...
                  'Position',[2, 1, 0], ...
                  'HorizontalAlignment','left', ...
                  'VerticalAlignment','bottom', ...
                  'FontSize',12, ...
                  'BackgroundColor','w', ...
                  'EdgeColor','k');
            axis(app.UIAxesTab2ContrastDifference, 'image');
            set(app.UIAxesTab2ContrastDifference, 'ydir', 'reverse');
            set(app.UIAxesTab2ContrastDifference, 'Visible', 'off');
            app.UIAxesTab2ContrastDifference.Title.Visible = 'on';
            hold(app.UIAxesTab2ContrastDifference, 'off');

            linkaxes([app.UIAxesTab2ContrastGrayscale, app.UIAxesTab2ContrastDifference], 'xy');

            pbar.Value = 0.9;
            app.data.tab1ImagePlot = imagesc(app.UIAxesTab1CenterROIs, img);
            colormap(app.UIAxesTab1CenterROIs, 'gray');
            axis(app.UIAxesTab1CenterROIs, 'image');
            set(app.UIAxesTab1CenterROIs, 'ydir', 'reverse');
            set(app.UIAxesTab1CenterROIs, 'Visible', 'off');
            pause(0.01);

            pbar.Value = 0.99;
            pause(0.1);
            close(pbar);
        end

        % Close request function: SetupEyeTrackingUIFigure
        function SetupEyeTrackingUIFigureCloseRequest(app, event)
            try
                [app.vars, allDone] = areAllTasksCompleted(app, app.vars);
                if ~allDone
                    confirmtxt = 'Setup tasks have not all been completed. Are you sure you would like to close this window?';
                    selection = uiconfirm(app.SetupEyeTrackingUIFigure, confirmtxt, 'Close Confirmation');
                    switch selection
                        case 'Cancel'
                            return
                        case 'OK'
                    end
                end
                [app.vars, app.trackParams, app.data] = updateTab1TrackParams(app, app.vars, app.trackParams, app.data);
                [app.trackParams] = updateTab2TrackParams(app, app.trackParams);
                [app.trackParams] = updateTab3TrackParams(app, app.trackParams);
                closeSetupEyeTrackingApp(app.ParentApp, app.trackParams, allDone);
            catch msgid
                fprintf('Error upon attempting to close Setup Eye Tracking App:\n');
                disp(msgid.message);
                delete(app)
            end
        end

        % Selection changed function: ToolSelectionButtonGroup
        function ToolSelectionButtonGroupSelectionChanged(app, event)
            selectedButton = app.ToolSelectionButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Pupil Center'
                    pupilCenterButton(app);
                case 'Pupil ROI'
                    pupilROIButton(app);
                case 'Left CR Marker'
                    leftCRMarkerButton(app);
                case 'Right CR Marker'
                    rightCRMarkerButton(app);
                case 'Show All'
                    app.Tab1InstructionsText.FontColor = 'k';
                    app.Tab1InstructionsText.Text = sprintf("Move, resize, or rotate any of the drawn shapes as needed. You may continue to the next tab.");
                    [app.vars, app.data] = setRoiVisibility(app, app.vars, app.data, ["on","on","on","on"]);
            end
        end

        % Value changing function: TimeBarSlider
        function TimeBarSliderValueChanging(app, event)
            app.vars.frame = round(event.Value);
            app.FrameNumberLabel.Text = sprintf(['Frame ',num2str(app.vars.frame)]);
            img = app.data.rawImgStack(:,:,app.vars.frame);
            switch app.TabGroup.SelectedTab.Title
                case "Select Pupil Center and ROIs"
                    set(app.data.tab1ImagePlot, 'CData',img);
                case "Adjust Contrast for Pupil"
                    updateTab2Plots(app, img, app.trackParams);
                case "Adjust Contrast for CRs"
                    updateTab3Plots(app, img, app.trackParams);
            end
            pause(0.003)
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            [app.vars, allDone] = areAllTasksCompleted(app, app.vars);
            if allDone
                img = app.data.rawImgStack(:,:,app.vars.frame);
                switch selectedTab.Title
                    case "Select Pupil Center and ROIs"
                        % Do nothing
                    case "Adjust Contrast for Pupil"
                        [app.vars, app.trackParams, app.data] = updateTab1TrackParams(app, app.vars, app.trackParams, app.data);
                        % Get estimated xy-limits of masked image
                        tempimg = imfilter(img, fspecial('gaussian',3,.5));
                        tempimg(~app.trackParams.maskPupil) = nan;
                        tempimg = double(tempimg);
                        aaa = find(nanmean(tempimg,1));
                        bbb = find(nanmean(tempimg,2));
                        app.data.tab2PlotXLim = [min(aaa), max(aaa)];
                        app.data.tab2PlotYLim = [min(bbb), max(bbb)];
                        % Update plots
                        updateTab2Plots(app, img, app.trackParams);
                    case "Adjust Contrast for CRs"
                        [app.vars, app.trackParams, app.data] = updateTab1TrackParams(app, app.vars, app.trackParams, app.data);
                        % Get estimated xy-limits of masked image
                        tempimg = imfilter(img, fspecial('gaussian',3,.5));
                        tempimg = double(tempimg);
                        aaa = find(nanmean(tempimg,1));
                        bbb = find(nanmean(tempimg,2));
                        app.data.tab3PlotXLim = [min(aaa), max(aaa)];
                        app.data.tab3PlotYLim = [min(bbb), max(bbb)];
                        % Update plots
                        updateTab3Plots(app, img, app.trackParams);
                end
            else
                app.TabGroup.SelectedTab = app.SelectPupilCenterandROIsTab;
                uialert(app.SetupEyeTrackingUIFigure, ...
                    'Pupil and CR centers and pupil ROIs are required to continue.', ...
                    'Missing Parameters');
            end
        end

        % Value changed function: RemoveBrightSpotsCheckBox
        function RemoveBrightSpotsCheckBoxValueChanged(app, event)
            value = app.RemoveBrightSpotsCheckBox.Value;
            if value
               app.SpotMaskRadiusEditField.Enable = "on";
            else
                app.SpotMaskRadiusEditField.Enable = "off";
            end
            img = app.data.rawImgStack(:,:,app.vars.frame);
            updateTab2Plots(app, img, app.trackParams);
        end

        % Value changed function: SpotMaskRadiusEditField
        function SpotMaskRadiusEditFieldValueChanged(app, event)
            img = app.data.rawImgStack(:,:,app.vars.frame);
            updateTab2Plots(app, img, app.trackParams);
        end

        % Value changed function: MinimumFeaturesEditField
        function MinimumFeaturesEditFieldValueChanged(app, event)
            img = app.data.rawImgStack(:,:,app.vars.frame);
            updateTab2Plots(app, img, app.trackParams);
        end

        % Value changed function: Tab2ContrastAdjustmentSlider
        function Tab2ContrastAdjustmentSliderValueChanged(app, event)
            img = app.data.rawImgStack(:,:,app.vars.frame);
            updateTab2Plots(app, img, app.trackParams);
        end

        % Value changed function: EdgeFeatureThresholdEditField
        function EdgeFeatureThresholdEditFieldValueChanged(app, event)
            img = app.data.rawImgStack(:,:,app.vars.frame);
            updateTab2Plots(app, img, app.trackParams);
        end

        % Value changed function: PupilRadiusLimits2EditField
        function PupilRadiusLimits2EditFieldValueChanged(app, event)
            img = app.data.rawImgStack(:,:,app.vars.frame);
            updateTab2Plots(app, img, app.trackParams);
        end

        % Value changed function: PupilRadiusLimits1EditField
        function PupilRadiusLimits1EditFieldValueChanged(app, event)
            img = app.data.rawImgStack(:,:,app.vars.frame);
            updateTab2Plots(app, img, app.trackParams);
        end

        % Value changed function: TopHatDiskRadiusEditField
        function TopHatDiskRadiusEditFieldValueChanged(app, event)
            app.trackParams.topHatDiskRadius = app.TopHatDiskRadiusEditField.Value;
            img = app.data.rawImgStack(:,:,app.vars.frame);
            updateTab3Plots(app, img, app.trackParams);            
        end

        % Value changed function: CostFunctionThreshEditField
        function CostFunctionThreshEditFieldValueChanged(app, event)
            app.trackParams.crTrackParams.qualityGateK = app.CostFunctionThreshEditField.Value;
            img = app.data.rawImgStack(:,:,app.vars.frame);
            updateTab3Plots(app, img, app.trackParams);
        end

        % Value changed function: VCenterPenaltyEditField
        function VCenterPenaltyEditFieldValueChanged(app, event)
            app.trackParams.crTrackParams.vertCenterPenalty = app.VCenterPenaltyEditField.Value;
            img = app.data.rawImgStack(:,:,app.vars.frame);
            updateTab3Plots(app, img, app.trackParams);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SetupEyeTrackingUIFigure = uifigure('Visible', 'off');
            app.SetupEyeTrackingUIFigure.Name = 'Setup Eye Tracking';
            app.SetupEyeTrackingUIFigure.CloseRequestFcn = createCallbackFcn(app, @SetupEyeTrackingUIFigureCloseRequest, true);
            app.GridLayoutTabGroup = uigridlayout(app.SetupEyeTrackingUIFigure);
            app.TabGroup = uitabgroup(app.GridLayoutTabGroup);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SelectPupilCenterandROIsTab = uitab(app.TabGroup);
            app.SelectPupilCenterandROIsTab.Title = 'Select Pupil Center and ROIs';
            app.GridLayoutTab1Rows = uigridlayout(app.SelectPupilCenterandROIsTab);
            app.GridLayoutTab1PlotRow = uigridlayout(app.GridLayoutTab1Rows);
            app.UIAxesTab1CenterROIs = uiaxes(app.GridLayoutTab1PlotRow);
            app.UIAxesTab1CenterROIs.XTick = [];
            app.UIAxesTab1CenterROIs.XTickLabel = '';
            app.UIAxesTab1CenterROIs.YTick = [];
            app.UIAxesTab1CenterROIs.YTickLabel = '';
            app.UIAxesTab1CenterROIs.ZTick = [];
            app.UIAxesTab1CenterROIs.ZTickLabel = '';
            app.GridLayoutTab1ToolsColumn = uigridlayout(app.GridLayoutTab1PlotRow);
            app.ToolSelectionButtonGroup = uibuttongroup(app.GridLayoutTab1ToolsColumn);
            app.ToolSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ToolSelectionButtonGroupSelectionChanged, true);
            app.ToolSelectionButtonGroup.Title = 'Tool Selection';
            app.NoneButton = uitogglebutton(app.ToolSelectionButtonGroup);
            app.NoneButton.Enable = 'off';
            app.NoneButton.Visible = 'off';
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.PupilROIButton = uitogglebutton(app.ToolSelectionButtonGroup);
            app.PupilROIButton.Enable = 'off';
            app.PupilROIButton.Text = 'Pupil ROI';
            app.LeftCRMarkerButton = uitogglebutton(app.ToolSelectionButtonGroup);
            app.LeftCRMarkerButton.Enable = 'off';
            app.LeftCRMarkerButton.Text = 'Left CR Marker';
            app.ShowAllButton = uitogglebutton(app.ToolSelectionButtonGroup);
            app.ShowAllButton.Enable = 'off';
            app.ShowAllButton.Text = 'Show All';
            app.PupilCenterButton = uitogglebutton(app.ToolSelectionButtonGroup);
            app.PupilCenterButton.Text = 'Pupil Center';
            app.RightCRMarkerButton = uitogglebutton(app.ToolSelectionButtonGroup);
            app.RightCRMarkerButton.Enable = 'off';
            app.RightCRMarkerButton.Text = 'Right CR Marker';
            app.GridLayoutTab1InstructionsRow = uigridlayout(app.GridLayoutTab1Rows);
            app.Tab1InstructionsBorder = uipanel(app.GridLayoutTab1InstructionsRow);
            app.Tab1InstructionsText = uilabel(app.GridLayoutTab1InstructionsRow);
            app.Tab1InstructionsText.Text = {'1. Select the "Pupil Center" tool on the left.'; '2. On the first frame, left-click the center of the pupil to set the tracking seed.'; 'Note: The marker is a seed for tracking from frame 1 — do not move or replace it on later frames.'};
            app.AdjustContrastforPupilTab = uitab(app.TabGroup);
            app.AdjustContrastforPupilTab.Title = 'Adjust Contrast for Pupil';
            app.GridLayoutTab2Rows = uigridlayout(app.AdjustContrastforPupilTab);
            app.GridLayoutTab2InstructionsRow = uigridlayout(app.GridLayoutTab2Rows);
            app.Tab2InstructionsBorder = uipanel(app.GridLayoutTab2InstructionsRow);
            app.Tab2InstructionsText = uilabel(app.GridLayoutTab2InstructionsRow);
            app.Tab2InstructionsText.Text = {'Raise the "low" adjustment until the inside of the pupil in the right-hand subplot is uniformly dark blue. '; 'Then raise the "high" adjustment until nearly all of the pupil edge has a value greater than "edgeThresh" (i.e. green).'};
            app.GridLayoutTab2PlotRow = uigridlayout(app.GridLayoutTab2Rows);
            app.UIAxesTab2ContrastDifference = uiaxes(app.GridLayoutTab2PlotRow);
            app.UIAxesTab2ContrastDifference.XTick = [];
            app.UIAxesTab2ContrastDifference.XTickLabel = '';
            app.UIAxesTab2ContrastDifference.YTick = [];
            app.UIAxesTab2ContrastDifference.YTickLabel = '';
            app.UIAxesTab2ContrastDifference.ZTick = [];
            app.UIAxesTab2ContrastDifference.ZTickLabel = '';
            app.UIAxesTab2ContrastGrayscale = uiaxes(app.GridLayoutTab2PlotRow);
            app.UIAxesTab2ContrastGrayscale.XTick = [];
            app.UIAxesTab2ContrastGrayscale.XTickLabel = '';
            app.UIAxesTab2ContrastGrayscale.YTick = [];
            app.UIAxesTab2ContrastGrayscale.YTickLabel = '';
            app.UIAxesTab2ContrastGrayscale.ZTick = [];
            app.UIAxesTab2ContrastGrayscale.ZTickLabel = '';
            app.UIAxesTab2ContrastGrayscaleTitle = uilabel(app.GridLayoutTab2PlotRow);
            app.UIAxesTab2ContrastGrayscaleTitle.Text = 'Cropped Grayscale Image';
            app.UIAxesTab2ContrastDifferenceTitle = uilabel(app.GridLayoutTab2PlotRow);
            app.UIAxesTab2ContrastDifferenceTitle.Text = 'Cropped Radially-Differenced Image';
            app.Tab2SliderRowBorder = uipanel(app.GridLayoutTab2Rows);
            app.GridLayoutTab2SliderRow = uigridlayout(app.Tab2SliderRowBorder);
            app.ContrastAdjustmentblackpointwhitepointLabel = uilabel(app.GridLayoutTab2SliderRow);
            app.ContrastAdjustmentblackpointwhitepointLabel.Text = 'Contrast Adjustment (low, high)';
            app.Tab2ContrastAdjustmentSlider = uislider(app.GridLayoutTab2SliderRow, 'range');
            app.Tab2ContrastAdjustmentSlider.Limits = [0 1];
            app.Tab2ContrastAdjustmentSlider.ValueChangedFcn = createCallbackFcn(app, @Tab2ContrastAdjustmentSliderValueChanged, true);
            app.Tab2ContrastAdjustmentSlider.Value = [0.1 0.85];
            app.Tab2SliderValuesText = uilabel(app.GridLayoutTab2SliderRow);
            app.Tab2SliderValuesText.Text = '(0.1, 0.85)';
            app.GridLayoutTab2SpotMaskingRow = uigridlayout(app.GridLayoutTab2SliderRow);
            app.RemoveBrightSpotsCheckBox = uicheckbox(app.GridLayoutTab2SpotMaskingRow);
            app.RemoveBrightSpotsCheckBox.ValueChangedFcn = createCallbackFcn(app, @RemoveBrightSpotsCheckBoxValueChanged, true);
            app.RemoveBrightSpotsCheckBox.Text = 'Remove Bright Spots';
            app.RemoveBrightSpotsCheckBox.Value = true;
            app.SpotMaskRadiusLabel = uilabel(app.GridLayoutTab2SpotMaskingRow);
            app.SpotMaskRadiusLabel.Text = 'Spot Mask Radius ';
            app.SpotMaskRadiusEditField = uieditfield(app.GridLayoutTab2SpotMaskingRow, 'numeric');
            app.SpotMaskRadiusEditField.Limits = [0 Inf];
            app.SpotMaskRadiusEditField.RoundFractionalValues = 'on';
            app.SpotMaskRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @SpotMaskRadiusEditFieldValueChanged, true);
            app.SpotMaskRadiusEditField.Value = 8;
            app.ColorbarLimitLabel = uilabel(app.GridLayoutTab2SpotMaskingRow);
            app.ColorbarLimitLabel.Text = 'Minimum Features ';
            app.MinimumFeaturesEditField = uieditfield(app.GridLayoutTab2SpotMaskingRow, 'numeric');
            app.MinimumFeaturesEditField.Limits = [0 1];
            app.MinimumFeaturesEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumFeaturesEditFieldValueChanged, true);
            app.MinimumFeaturesEditField.Value = 0.6;
            app.EdgeFeatureThresholdEditFieldLabel = uilabel(app.GridLayoutTab2SpotMaskingRow);
            app.EdgeFeatureThresholdEditFieldLabel.Text = 'Edge Feature Threshold ';
            app.EdgeFeatureThresholdEditField = uieditfield(app.GridLayoutTab2SpotMaskingRow, 'numeric');
            app.EdgeFeatureThresholdEditField.Limits = [0 255];
            app.EdgeFeatureThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @EdgeFeatureThresholdEditFieldValueChanged, true);
            app.EdgeFeatureThresholdEditField.Value = 40;
            app.RadiusLimitsLabel = uilabel(app.GridLayoutTab2SpotMaskingRow);
            app.RadiusLimitsLabel.Text = 'Radius Limits ';
            app.PupilRadiusLimits1EditField = uieditfield(app.GridLayoutTab2SpotMaskingRow, 'numeric');
            app.PupilRadiusLimits1EditField.Limits = [0 Inf];
            app.PupilRadiusLimits1EditField.ValueChangedFcn = createCallbackFcn(app, @PupilRadiusLimits1EditFieldValueChanged, true);
            app.PupilRadiusLimits1EditField.Value = 30;
            app.PupilRadiusLimits2EditField = uieditfield(app.GridLayoutTab2SpotMaskingRow, 'numeric');
            app.PupilRadiusLimits2EditField.Limits = [0 Inf];
            app.PupilRadiusLimits2EditField.ValueChangedFcn = createCallbackFcn(app, @PupilRadiusLimits2EditFieldValueChanged, true);
            app.PupilRadiusLimits2EditField.Value = 80;
            app.AdjustContrastforCRsTab = uitab(app.TabGroup);
            app.AdjustContrastforCRsTab.Title = 'Adjust Contrast for CRs';
            app.GridLayoutTab3Rows = uigridlayout(app.AdjustContrastforCRsTab);
            app.GridLayoutTab3InstructionsRow = uigridlayout(app.GridLayoutTab3Rows);
            app.Tab3InstructionsBorder = uipanel(app.GridLayoutTab3InstructionsRow);
            app.Tab3InstructionsText = uilabel(app.GridLayoutTab3InstructionsRow);
            app.Tab3InstructionsText.Text = {'Adjust the parameters as needed based on '; 'how robust the CR detection is for the Circular Hough Transform on the right-hand subplot.'};
            app.GridLayoutTab3PlotRow = uigridlayout(app.GridLayoutTab3Rows);
            app.UIAxesTab3ContrastGrayscale = uiaxes(app.GridLayoutTab3PlotRow);
            app.UIAxesTab3ContrastGrayscale.XTick = [];
            app.UIAxesTab3ContrastGrayscale.XTickLabel = '';
            app.UIAxesTab3ContrastGrayscale.YTick = [];
            app.UIAxesTab3ContrastGrayscale.YTickLabel = '';
            app.UIAxesTab3ContrastGrayscale.ZTick = [];
            app.UIAxesTab3ContrastGrayscale.ZTickLabel = '';
            app.UIAxesTab3ContrastHough = uiaxes(app.GridLayoutTab3PlotRow);
            app.UIAxesTab3ContrastHough.XTick = [];
            app.UIAxesTab3ContrastHough.XTickLabel = '';
            app.UIAxesTab3ContrastHough.YTick = [];
            app.UIAxesTab3ContrastHough.YTickLabel = '';
            app.UIAxesTab3ContrastHough.ZTick = [];
            app.UIAxesTab3ContrastHough.ZTickLabel = '';
            app.UIAxesTab3ContrastHoughTitle = uilabel(app.GridLayoutTab3PlotRow);
            app.UIAxesTab3ContrastHoughTitle.Text = 'Circular Hough-Transformed Image';
            app.UIAxesTab3ContrastGrayscaleTitle = uilabel(app.GridLayoutTab3PlotRow);
            app.UIAxesTab3ContrastGrayscaleTitle.Text = 'Top-Hat Filtered Image';
            app.Tab3SliderRowBorder = uipanel(app.GridLayoutTab3Rows);
            app.GridLayoutTab3SliderRow = uigridlayout(app.Tab3SliderRowBorder);
            app.GridLayoutTab3CRParamRow1 = uigridlayout(app.GridLayoutTab3SliderRow);
            app.TopHatDiskRadiusLabel = uilabel(app.GridLayoutTab3CRParamRow1);
            app.TopHatDiskRadiusLabel.Text = 'Top-Hat Disk Radius ';
            app.TopHatDiskRadiusEditField = uieditfield(app.GridLayoutTab3CRParamRow1, 'numeric');
            app.TopHatDiskRadiusEditField.Limits = [1 50];
            app.TopHatDiskRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @TopHatDiskRadiusEditFieldValueChanged, true);
            app.TopHatDiskRadiusEditField.Value = 15;
            app.CostFunctionThreshLabel = uilabel(app.GridLayoutTab3CRParamRow1);
            app.CostFunctionThreshLabel.Text = 'Cost Function Thresh ';
            app.CostFunctionThreshEditField = uieditfield(app.GridLayoutTab3CRParamRow1, 'numeric');
            app.CostFunctionThreshEditField.Limits = [0 Inf];
            app.CostFunctionThreshEditField.ValueChangedFcn = createCallbackFcn(app, @CostFunctionThreshEditFieldValueChanged, true);
            app.CostFunctionThreshEditField.Value = 60;
            app.RadiusLimitsLabel_3 = uilabel(app.GridLayoutTab3CRParamRow1);
            app.RadiusLimitsLabel_3.Text = 'V-Center Penalty ';
            app.VCenterPenaltyEditField = uieditfield(app.GridLayoutTab3CRParamRow1, 'numeric');
            app.VCenterPenaltyEditField.Limits = [0 Inf];
            app.VCenterPenaltyEditField.ValueChangedFcn = createCallbackFcn(app, @VCenterPenaltyEditFieldValueChanged, true);
            app.VCenterPenaltyEditField.Value = 0.5;
            app.GridLayoutTab3CRParamRow2 = uigridlayout(app.GridLayoutTab3SliderRow);
            app.CRRadiusLimits2EditField = uieditfield(app.GridLayoutTab3CRParamRow2, 'numeric');
            app.CRRadiusLimits2EditField.Limits = [0 Inf];
            app.CRRadiusLimits2EditField.Value = 13;
            app.RadiusLimitsLabel_2 = uilabel(app.GridLayoutTab3CRParamRow2);
            app.RadiusLimitsLabel_2.Text = 'CR Radius Limits ';
            app.CRRadiusLimits1EditField = uieditfield(app.GridLayoutTab3CRParamRow2, 'numeric');
            app.CRRadiusLimits1EditField.Limits = [0 Inf];
            app.CRRadiusLimits1EditField.Value = 6;
            app.GridLayoutTab3CRParamRow3 = uigridlayout(app.GridLayoutTab3SliderRow);
            app.GridLayoutPanelTimeBar = uigridlayout(app.GridLayoutTabGroup);
            app.PanelTimeBar = uipanel(app.GridLayoutPanelTimeBar);
            app.GridLayoutTimeBar = uigridlayout(app.PanelTimeBar);
            app.TimeBarSlider = uislider(app.GridLayoutTimeBar);
            app.TimeBarSlider.Limits = [1 3600];
            app.TimeBarSlider.ValueChangingFcn = createCallbackFcn(app, @TimeBarSliderValueChanging, true);
            app.TimeBarSlider.MinorTicks = [];
            app.TimeBarSlider.Enable = 'off';
            app.TimeBarSlider.Value = 1;
            app.FrameNumberLabel = uilabel(app.GridLayoutTimeBar);
            app.FrameNumberLabel.Text = 'Frame 1';
            % Show the figure after all components are created
            app.SetupEyeTrackingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = setupEyeTracking(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SetupEyeTrackingUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SetupEyeTrackingUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SetupEyeTrackingUIFigure)
        end
    end
end