classdef manualFrameRedo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ManualFrameRedoUIFigure    matlab.ui.Figure
        GridLayoutTabGroup         matlab.ui.container.GridLayout
        TabGroup                   matlab.ui.container.TabGroup
        RedoPupilandCRsTab         matlab.ui.container.Tab
        GridLayoutRows             matlab.ui.container.GridLayout
        GridLayoutInstructionsRow  matlab.ui.container.GridLayout
        Tab1InstructionsText       matlab.ui.control.Label
        Tab1InstructionsBorder     matlab.ui.container.Panel
        GridLayoutPlotRow          matlab.ui.container.GridLayout
        GridLayoutToolsColumn      matlab.ui.container.GridLayout
        TakenActionButtonGroup     matlab.ui.container.ButtonGroup
        UpdateFrameDataUButton     matlab.ui.control.RadioButton
        SetasNaNNButton            matlab.ui.control.RadioButton
        NoChangeButton             matlab.ui.control.RadioButton
        ManualFrameReDosPanel      matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        BadFramesListBox           matlab.ui.control.ListBox
        UIAxesPupilCRs             matlab.ui.control.UIAxes
        UIAxesPupilCRsNext         matlab.ui.control.UIAxes
        UIAxesPupilCRsPrev         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ParentApp; % Main app
    end
    
    properties (Access = public)
        vars;
        trackParams;
        frameData;
        data;
    end
    
    methods (Access = private)
        
        function [frameData, vars] = setTakenAction(app, frame, frameIdx, selection, frameData, vars, data)
            switch selection
                case "Update Frame Data (U)"
                    vars.framesListStatus(frameIdx) = 1;
                    app.data.ellipsePupilPlot.Visible = "on";
                    app.data.circleCR1Plot.Visible = "on";
                    app.data.circleCR2Plot.Visible = "on";
                    frameData(frame).cr1_x = data.circleCR1Plot.Center(1);
                    frameData(frame).cr1_y = data.circleCR1Plot.Center(2);
                    frameData(frame).cr1_r = data.circleCR1Plot.Radius;
            
                    frameData(frame).cr2_x = data.circleCR2Plot.Center(1);
                    frameData(frame).cr2_y = data.circleCR2Plot.Center(2);
                    frameData(frame).cr2_r = data.circleCR2Plot.Radius;
            
                    frameData(frame).pupil_x = data.ellipsePupilPlot.Center(1);
                    frameData(frame).pupil_y = data.ellipsePupilPlot.Center(2);
                    frameData(frame).pupil_r1 = data.ellipsePupilPlot.SemiAxes(2);
                    frameData(frame).pupil_r2 = data.ellipsePupilPlot.SemiAxes(1);
                    frameData(frame).pupil_angle = data.ellipsePupilPlot.RotationAngle;
                case "Set as NaN (N)"
                    vars.framesListStatus(frameIdx) = nan;
                    app.data.ellipsePupilPlot.Visible = "off";
                    app.data.circleCR1Plot.Visible = "off";
                    app.data.circleCR2Plot.Visible = "off";
                    frameData(frame).cr1_x = nan;
                    frameData(frame).cr1_y = nan;
                    frameData(frame).cr1_r = nan;
            
                    frameData(frame).cr2_x = nan;
                    frameData(frame).cr2_y = nan;
                    frameData(frame).cr2_r = nan;
            
                    frameData(frame).pupil_x = nan;
                    frameData(frame).pupil_y = nan;
                    frameData(frame).pupil_r1 = nan;
                    frameData(frame).pupil_r2 = nan;
                    frameData(frame).pupil_angle = nan;
                otherwise
                    vars.framesListStatus(frameIdx) = 0;
                    app.data.ellipsePupilPlot.Visible = "on";
                    app.data.circleCR1Plot.Visible = "on";
                    app.data.circleCR2Plot.Visible = "on";
            end
        end

        function getFrameData(app, frame, frameData)
            app.data.circleCR1Plot.Center(1) = frameData(frame).cr1_x;
            app.data.circleCR1Plot.Center(2) = frameData(frame).cr1_y;
            app.data.circleCR1Plot.Radius = frameData(frame).cr1_r;
    
            app.data.circleCR2Plot.Center(1) = frameData(frame).cr2_x;
            app.data.circleCR2Plot.Center(2) = frameData(frame).cr2_y;
            app.data.circleCR2Plot.Radius = frameData(frame).cr2_r;
    
            app.data.ellipsePupilPlot.Center(1) = frameData(frame).pupil_x;
            app.data.ellipsePupilPlot.Center(2) = frameData(frame).pupil_y;
            app.data.ellipsePupilPlot.SemiAxes(2) = frameData(frame).pupil_r1;
            app.data.ellipsePupilPlot.SemiAxes(1) = frameData(frame).pupil_r2;
            app.data.ellipsePupilPlot.RotationAngle = frameData(frame).pupil_angle;
        end

        function loadFrame(app)
            frame = str2double(app.BadFramesListBox.Value);
            frameIdx = app.BadFramesListBox.ValueIndex;
            app.vars.prevFrame = frame;
        
            % Update images
            app.data.imgCurr.CData = readImg([0.05, 0.95], app.trackParams.cam, frame);
            title(app.UIAxesPupilCRs, sprintf('Frame %d', frame), 'FontSize', 18);
            try app.data.imgPrev.CData = readImg([0.05, 0.95], app.trackParams.cam, frame-1); catch; end
            try app.data.imgNext.CData = readImg([0.05, 0.95], app.trackParams.cam, frame+1); catch; end
            title(app.UIAxesPupilCRsPrev, sprintf('Frame %d', frame-1), 'FontSize', 18);
            title(app.UIAxesPupilCRsNext, sprintf('Frame %d', frame+1), 'FontSize', 18);
        
            % Find seed frame (current if valid, else nearest good frame)
            seedFrame = findNearestGoodFrame(app, frame, app.frameData);
        
            % Reposition shapes to seed frame data
            getFrameData(app, seedFrame, app.frameData);
        
            % Restore state based on previous action
            if isnan(app.vars.framesListStatus(frameIdx))
                app.SetasNaNNButton.Value = 1;
                seedFrame = findNearestGoodFrame(app, frame, app.frameData);
                getFrameData(app, seedFrame, app.frameData);
                app.data.ellipsePupilPlot.Visible = "off";
                app.data.circleCR1Plot.Visible = "off";
                app.data.circleCR2Plot.Visible = "off";
            elseif app.vars.framesListStatus(frameIdx) == 1
                app.UpdateFrameDataUButton.Value = 1;
                getFrameData(app, frame, app.frameData);
                app.data.ellipsePupilPlot.Visible = "on";
                app.data.circleCR1Plot.Visible = "on";
                app.data.circleCR2Plot.Visible = "on";
            else
                app.NoChangeButton.Value = 1;
                seedFrame = findNearestGoodFrame(app, frame, app.frameData);
                getFrameData(app, seedFrame, app.frameData);
                app.data.ellipsePupilPlot.Visible = "on";
                app.data.circleCR1Plot.Visible = "on";
                app.data.circleCR2Plot.Visible = "on";
            end
        end

        function result = isFrameComplete(~, f)
            result = ~isnan(f.pupil_x) && ~isnan(f.pupil_r1) && ...
                     ~isnan(f.cr1_x) && ~isnan(f.cr1_r) && ...
                     ~isnan(f.cr2_x) && ~isnan(f.cr2_r);
        end
        
        function seedFrame = findNearestGoodFrame(app, frame, frameData)
            seedFrame = frame;
            if ~isFrameComplete(app, frameData(frame))
                for j = frame-1:-1:1
                    if isFrameComplete(app, frameData(j))
                        seedFrame = j; return;
                    end
                end
                for j = frame+1:numel(frameData)
                    if isFrameComplete(app, frameData(j))
                        seedFrame = j; return;
                    end
                end
            end
        end

        function advanceToNextFrame(app, currentIdx)
            if currentIdx < numel(app.BadFramesListBox.Items)
                app.BadFramesListBox.ValueIndex = currentIdx + 1;
                loadFrame(app);
            end
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentapp, trackParams, frameData, frame, framesList)
            app.ParentApp = parentapp;
            app.trackParams = trackParams;
            app.frameData = frameData;
            app.vars.prevFrame = frame;
            if ~exist('framesList', 'var')
                framesList =  {int2str(frame)};
            end
            app.BadFramesListBox.Items = framesList;
            app.BadFramesListBox.Value = num2str(frame);
            app.vars.framesListStatus = zeros(1,length(framesList));
            % Initialize flag variables
            app.data.ellipsePupilCompleted = 0;
            app.data.circleCR1Completed = 0;
            app.data.circleCR2Completed = 0;
            % Plot figure
            img = readImg([0.05, 0.95], trackParams.cam, frame);
            cla(app.UIAxesPupilCRs);
            cla(app.UIAxesPupilCRsPrev);
            cla(app.UIAxesPupilCRsNext);
            app.data.imgCurr = imagesc(app.UIAxesPupilCRs, img);
            % Find nearest good frame to seed the shapes
            seedFrame = findNearestGoodFrame(app, frame, frameData);
            if ~isFrameComplete(app, frameData(seedFrame))
                % No complete frame found anywhere — use default placeholder positions
                % (center of image with reasonable radii)
                imgCenter = round(size(img) / 2);
                frameData(seedFrame).pupil_x = imgCenter(2);
                frameData(seedFrame).pupil_y = imgCenter(1);
                frameData(seedFrame).pupil_r1 = 30;
                frameData(seedFrame).pupil_r2 = 25;
                frameData(seedFrame).pupil_angle = 0;
                frameData(seedFrame).cr1_x = imgCenter(2) - 20;
                frameData(seedFrame).cr1_y = imgCenter(1) - 20;
                frameData(seedFrame).cr1_r = 5;
                frameData(seedFrame).cr2_x = imgCenter(2) + 20;
                frameData(seedFrame).cr2_y = imgCenter(1) - 20;
                frameData(seedFrame).cr2_r = 5;
            end
            % Pre-draw all shapes immediately (no sequential button clicking)
            hold(app.UIAxesPupilCRs, 'on');
            app.data.ellipsePupilPlot = drawellipse(app.UIAxesPupilCRs, ...
                'Center', [frameData(seedFrame).pupil_x, frameData(seedFrame).pupil_y], ...
                'SemiAxes', [frameData(seedFrame).pupil_r2, frameData(seedFrame).pupil_r1], ...
                'RotationAngle', frameData(seedFrame).pupil_angle, ...
                'Color', 'r');
            app.data.circleCR1Plot = drawcircle(app.UIAxesPupilCRs, ...
                'Center', [frameData(seedFrame).cr1_x, frameData(seedFrame).cr1_y], ...
                'Radius', frameData(seedFrame).cr1_r, ...
                'Color', 'b');
            app.data.circleCR2Plot = drawcircle(app.UIAxesPupilCRs, ...
                'Center', [frameData(seedFrame).cr2_x, frameData(seedFrame).cr2_y], ...
                'Radius', frameData(seedFrame).cr2_r, ...
                'Color', 'c');
            hold(app.UIAxesPupilCRs, 'off');
            app.data.ellipsePupilCompleted = 1;
            app.data.circleCR1Completed = 1;
            app.data.circleCR2Completed = 1;
            title(app.UIAxesPupilCRs, sprintf(['Frame ',num2str(frame)]), 'FontSize',18);
            colormap(app.UIAxesPupilCRs, 'gray');
            axis(app.UIAxesPupilCRs, 'image');
            set(app.UIAxesPupilCRs, 'ydir', 'reverse');
            set(app.UIAxesPupilCRs, 'Visible', 'off');
            set(get(app.UIAxesPupilCRs,'Title'), 'Visible','on');
            try
                imgPrev = readImg([0.05, 0.95], trackParams.cam, frame-1);
            catch
                imgPrev = zeros(size(img));
            end
            app.data.imgPrev = imagesc(app.UIAxesPupilCRsPrev, imgPrev);
            title(app.UIAxesPupilCRsPrev, sprintf(['Frame ',num2str(frame-1)]), 'FontSize',18);
            colormap(app.UIAxesPupilCRsPrev, 'gray');
            axis(app.UIAxesPupilCRsPrev, 'image');
            set(app.UIAxesPupilCRsPrev, 'ydir', 'reverse');
            set(app.UIAxesPupilCRsPrev, 'Visible', 'off');
            set(get(app.UIAxesPupilCRsPrev,'Title'), 'Visible','on');
            try
                imgNext = readImg([0.05, 0.95], trackParams.cam, frame+1);
            catch
                imgNext = zeros(size(img));
            end
            app.data.imgNext = imagesc(app.UIAxesPupilCRsNext, imgNext);
            title(app.UIAxesPupilCRsNext, sprintf(['Frame ',num2str(frame+1)]), 'FontSize',18);
            colormap(app.UIAxesPupilCRsNext, 'gray');
            axis(app.UIAxesPupilCRsNext, 'image');
            set(app.UIAxesPupilCRsNext, 'ydir', 'reverse');
            set(app.UIAxesPupilCRsNext, 'Visible', 'off');
            set(get(app.UIAxesPupilCRsNext,'Title'), 'Visible','on');
        end

        % Close request function: ManualFrameRedoUIFigure
        function ManualFrameRedoUIFigureCloseRequest(app, event)
            try
                framesListStatus = app.vars.framesListStatus;
                framesList = app.BadFramesListBox.Items;
                unassignedFrameIds = find(framesListStatus == 0);
                if ~isempty(unassignedFrameIds)
                    unassignedFrames = framesList(unassignedFrameIds');
                    unassignedFrameNumbers = cellfun(@(x) str2double(x), unassignedFrames);
                    % unassignedStrings = cellfun(@(c) c{1}, unassignedFrames, 'UniformOutput', false);
                    unassignedText = strjoin(unassignedFrames, ', ');
                    confirmtxt = sprintf(['The following frames were not assigned an action:\n\n', ...
                                          unassignedText, ...
                                          '\n\nWhat action would you like performed on these frames?']);
                    selection = uiconfirm(app.ManualFrameRedoUIFigure, ...
                                          confirmtxt, ...
                                          'Close Confirmation', ...
                                          'Options',["Proceed with No Changes","Set Frames as NaN","Cancel"], ...
                                          'DefaultOption',1, ...
                                          'CancelOption',3);
                    if selection == "Cancel"
                        return
                    elseif selection == "Set Frames as NaN"
                        for i = 1:length(unassignedFrameNumbers)
                            frame = unassignedFrameNumbers(i);
                            app.frameData(frame).cr1_x = nan;
                            app.frameData(frame).cr1_y = nan;
                            app.frameData(frame).cr1_r = nan;
                    
                            app.frameData(frame).cr2_x = nan;
                            app.frameData(frame).cr2_y = nan;
                            app.frameData(frame).cr2_r = nan;
                    
                            app.frameData(frame).pupil_x = nan;
                            app.frameData(frame).pupil_y = nan;
                            app.frameData(frame).pupil_r1 = nan;
                            app.frameData(frame).pupil_r2 = nan;
                            app.frameData(frame).pupil_angle = nan;
                        end
                    end
                end
                closeManualFrameRedoApp(app.ParentApp, app.frameData);
            catch msgid
                fprintf('Error upon attempting to close Manual Frame Redo App:\n');
                disp(msgid.message);
                delete(app)
            end
        end

        % Callback function: not associated with a component
        function ToolSelectionButtonGroupSelectionChanged(app, event)

        end

        % Value changed function: BadFramesListBox
        function BadFramesListBoxValueChanged(app, event)
            loadFrame(app);
        end

        % Selection changed function: TakenActionButtonGroup
        function TakenActionButtonGroupSelectionChanged(app, event)
            selection = app.TakenActionButtonGroup.SelectedObject.Text;
            frame = str2double(app.BadFramesListBox.Value);
            frameIdx = app.BadFramesListBox.ValueIndex;
            [app.frameData, app.vars] = setTakenAction(app, frame, frameIdx, selection, app.frameData, app.vars, app.data);
        end

        % Window key press function: ManualFrameRedoUIFigure
        function ManualFrameRedoUIFigureKeyPress(app, event)
            key = event.Key;
            frame = str2double(app.BadFramesListBox.Value);
            frameIdx = app.BadFramesListBox.ValueIndex;
            switch key
                case '1'
                    if strcmp(app.data.ellipsePupilPlot.Visible, 'on')
                        app.data.ellipsePupilPlot.Visible = 'off';
                    else
                        app.data.ellipsePupilPlot.Visible = 'on';
                    end
                case '2'
                    if strcmp(app.data.circleCR1Plot.Visible, 'on')
                        app.data.circleCR1Plot.Visible = 'off';
                    else
                        app.data.circleCR1Plot.Visible = 'on';
                    end
                case '3'
                    if strcmp(app.data.circleCR2Plot.Visible, 'on')
                        app.data.circleCR2Plot.Visible = 'off';
                    else
                        app.data.circleCR2Plot.Visible = 'on';
                    end
                case 'n'
                    app.SetasNaNNButton.Value = 1;
                    [app.frameData, app.vars] = setTakenAction(app, frame, frameIdx, "Set as NaN (N)", app.frameData, app.vars, app.data);
                    advanceToNextFrame(app, frameIdx);
                case 'u'
                    app.UpdateFrameDataUButton.Value = 1;
                    [app.frameData, app.vars] = setTakenAction(app, frame, frameIdx, "Update Frame Data (U)", app.frameData, app.vars, app.data);
                    advanceToNextFrame(app, frameIdx);
                case 'downarrow'  % Next frame (no action)
                    advanceToNextFrame(app, frameIdx);
                case 'uparrow'  % Previous frame (no action)
                    if frameIdx > 1
                        app.BadFramesListBox.ValueIndex = frameIdx - 1;
                        loadFrame(app);
                    end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ManualFrameRedoUIFigure = uifigure('Visible', 'off');
            app.ManualFrameRedoUIFigure.Name = 'Manual Frame Redo';
            app.ManualFrameRedoUIFigure.CloseRequestFcn = createCallbackFcn(app, @ManualFrameRedoUIFigureCloseRequest, true);
            app.ManualFrameRedoUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @ManualFrameRedoUIFigureKeyPress, true);
            app.GridLayoutTabGroup = uigridlayout(app.ManualFrameRedoUIFigure);
            app.TabGroup = uitabgroup(app.GridLayoutTabGroup);
            app.RedoPupilandCRsTab = uitab(app.TabGroup);
            app.RedoPupilandCRsTab.Title = 'Redo Pupil and CRs';
            app.GridLayoutRows = uigridlayout(app.RedoPupilandCRsTab);
            app.GridLayoutPlotRow = uigridlayout(app.GridLayoutRows);
            app.UIAxesPupilCRsPrev = uiaxes(app.GridLayoutPlotRow);
            app.UIAxesPupilCRsPrev.XTick = [];
            app.UIAxesPupilCRsPrev.XTickLabel = '';
            app.UIAxesPupilCRsPrev.YTick = [];
            app.UIAxesPupilCRsPrev.YTickLabel = '';
            app.UIAxesPupilCRsPrev.ZTick = [];
            app.UIAxesPupilCRsPrev.ZTickLabel = '';
            app.UIAxesPupilCRsNext = uiaxes(app.GridLayoutPlotRow);
            app.UIAxesPupilCRsNext.XTick = [];
            app.UIAxesPupilCRsNext.XTickLabel = '';
            app.UIAxesPupilCRsNext.YTick = [];
            app.UIAxesPupilCRsNext.YTickLabel = '';
            app.UIAxesPupilCRsNext.ZTick = [];
            app.UIAxesPupilCRsNext.ZTickLabel = '';
            app.UIAxesPupilCRs = uiaxes(app.GridLayoutPlotRow);
            app.UIAxesPupilCRs.XTick = [];
            app.UIAxesPupilCRs.XTickLabel = '';
            app.UIAxesPupilCRs.YTick = [];
            app.UIAxesPupilCRs.YTickLabel = '';
            app.UIAxesPupilCRs.ZTick = [];
            app.UIAxesPupilCRs.ZTickLabel = '';
            app.GridLayoutToolsColumn = uigridlayout(app.GridLayoutPlotRow);
            app.ManualFrameReDosPanel = uipanel(app.GridLayoutToolsColumn);
            app.ManualFrameReDosPanel.Title = 'Manual Frame Re-Dos';
            app.GridLayout3 = uigridlayout(app.ManualFrameReDosPanel);
            app.BadFramesListBox = uilistbox(app.GridLayout3);
            app.BadFramesListBox.Items = {'None'};
            app.BadFramesListBox.ValueChangedFcn = createCallbackFcn(app, @BadFramesListBoxValueChanged, true);
            app.BadFramesListBox.Value = 'None';
            app.TakenActionButtonGroup = uibuttongroup(app.GridLayoutToolsColumn);
            app.TakenActionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TakenActionButtonGroupSelectionChanged, true);
            app.TakenActionButtonGroup.Title = 'Taken Action';
            app.NoChangeButton = uiradiobutton(app.TakenActionButtonGroup);
            app.NoChangeButton.Text = 'No Change';
            app.NoChangeButton.Value = true;
            app.SetasNaNNButton = uiradiobutton(app.TakenActionButtonGroup);
            app.SetasNaNNButton.Text = 'Set as NaN (N)';
            app.UpdateFrameDataUButton = uiradiobutton(app.TakenActionButtonGroup);
            app.UpdateFrameDataUButton.Text = 'Update Frame Data (U)';
            app.GridLayoutInstructionsRow = uigridlayout(app.GridLayoutRows);
            app.Tab1InstructionsBorder = uipanel(app.GridLayoutInstructionsRow);
            app.Tab1InstructionsText = uilabel(app.GridLayoutInstructionsRow);
            app.Tab1InstructionsText.Text = 'Adjust the pre-drawn shapes as needed. Press U to update, N to mark as NaN, or arrow keys to navigate.';
            % Show the figure after all components are created
            app.ManualFrameRedoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = manualFrameRedo(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ManualFrameRedoUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ManualFrameRedoUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ManualFrameRedoUIFigure)
        end
    end
end